classdef AppAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analyzer                       matlab.ui.Figure
        TESDataMenu                    matlab.ui.container.Menu
        LoadTESMenu                    matlab.ui.container.Menu
        TESAnalysisMenu                matlab.ui.container.Menu
        SetDataPathMenu                matlab.ui.container.Menu
        TESDeviceMenu                  matlab.ui.container.Menu
        TESDimensionsMenu              matlab.ui.container.Menu
        SQUIDTemperatureMenu           matlab.ui.container.Menu
        CircuitValuesMenu              matlab.ui.container.Menu
        CircuitNoiseMenu               matlab.ui.container.Menu
        IVCurvesMenu                   matlab.ui.container.Menu
        ImportIVCurvesMenu             matlab.ui.container.Menu
        CenterIVCurvesMenu             matlab.ui.container.Menu
        CheckIVCurvesMenu              matlab.ui.container.Menu
        SaveIVCurveSetMenu             matlab.ui.container.Menu
        FitPvsTMenu                    matlab.ui.container.Menu
        TESThermalParametersvsRnMenu   matlab.ui.container.Menu
        TESThermalParameterValuesMenu  matlab.ui.container.Menu
        GetGTMenu                      matlab.ui.container.Menu
        SuperconductorStateMenu        matlab.ui.container.Menu
        LoadTFinSuperconductorStateTFSMenu  matlab.ui.container.Menu
        CheckTFSMenu                   matlab.ui.container.Menu
        LoadNoiseinSuperconductorStateMenu  matlab.ui.container.Menu
        CheckSuperconductorStateNoiseMenu  matlab.ui.container.Menu
        NormalStateMenu                matlab.ui.container.Menu
        LoadTFinNormalStateTFNMenu     matlab.ui.container.Menu
        CheckTFNMenu                   matlab.ui.container.Menu
        LoadNoiseinNormalStateMenu     matlab.ui.container.Menu
        CheckNormalStateNoiseMenu      matlab.ui.container.Menu
        ZwNoiseAnalysisMenu            matlab.ui.container.Menu
        ZwDerivedLMenu                 matlab.ui.container.Menu
        FitZwNoisetoElectroThermalModelMenu  matlab.ui.container.Menu
        CriticalCurrentsMenu           matlab.ui.container.Menu
        ImportCriticalCurrentsMenu     matlab.ui.container.Menu
        FieldScanMenu                  matlab.ui.container.Menu
        ImportFieldScanMenu            matlab.ui.container.Menu
        UnloadTESMenu                  matlab.ui.container.Menu
        ReAnalyzeLoadedTESMenu         matlab.ui.container.Menu
        SaveTESDataMenu                matlab.ui.container.Menu
        TESResolutionMenu              matlab.ui.container.Menu
        LoadPulseSet                   matlab.ui.container.Menu
        IdenKaNoise                    matlab.ui.container.Menu
        PlotPulses                     matlab.ui.container.Menu
        PlotKa                         matlab.ui.container.Menu
        PlotNoise                      matlab.ui.container.Menu
        PlotOF                         matlab.ui.container.Menu
        Plotclust                      matlab.ui.container.Menu
        BuildOptimalFilter             matlab.ui.container.Menu
        RemoveOutliers                 matlab.ui.container.Menu
        ClusteringDatabyDClevel        matlab.ui.container.Menu
        TESResolutionestimation        matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        PlotNKGTSetMenu                matlab.ui.container.Menu
        PlotRTsSetMenu                 matlab.ui.container.Menu
        PlotRT4pointsMenu              matlab.ui.container.Menu
        PlotABCTSetMenu                matlab.ui.container.Menu
        PlotZwvsRnMenu                 matlab.ui.container.Menu
        PlotNoisevsRnMenu              matlab.ui.container.Menu
        PlotTESDataMenu                matlab.ui.container.Menu
        PlotIVZMenu                    matlab.ui.container.Menu
        PlotCriticalCurrentsMenu       matlab.ui.container.Menu
        PlotFieldScanMenu              matlab.ui.container.Menu
        MacroMenu                      matlab.ui.container.Menu
        PlotNKGTSetsMenu               matlab.ui.container.Menu
        PlotRTsSetsMenu                matlab.ui.container.Menu
        PlotRTs4pointsMenu             matlab.ui.container.Menu
        PlotABCTSetsMenu               matlab.ui.container.Menu
        PlotTESsDataMenu               matlab.ui.container.Menu
        PlotIVZsMenu                   matlab.ui.container.Menu
        PlotsCriticalCurrentsMenu      matlab.ui.container.Menu
        PlotsFieldScanMenu             matlab.ui.container.Menu
        SummaryMenu                    matlab.ui.container.Menu
        ZwNoiseViewerMenu              matlab.ui.container.Menu
        WordGraphicalReportMenu        matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        PvsTModelFittingOptionsMenu    matlab.ui.container.Menu
        ElectroThermalModelFittingOptionsMenu  matlab.ui.container.Menu
        ReportOptionsMenu              matlab.ui.container.Menu
        Help                           matlab.ui.container.Menu
        Guide                          matlab.ui.container.Menu
        About                          matlab.ui.container.Menu
        PulsesSetName                  matlab.ui.control.DropDown
        LoadedTESLabel_2               matlab.ui.control.Label
        Loaded_TES                     matlab.ui.control.DropDown
        LoadedTESLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        VersionStr = 'ZarTES 5.0'; % Description
        Session;
        TES_ID = 0;
        DataBasePath = 'C:\Users\jbolea\Documents\GitHub\ZartesGui\DataBase\';
        DataBaseName = 'zartesdb';
        Username = 'jbolea';
        Password = 'jbolea';
        Save_menu;
        NKGT_menu;
        OF;
        PulseSet;

    end
    
    methods (Access = private)
        
        function Enabling(app, Session,TES_ID,fig)

            
            StrEnable = {'on';'off'};
            % Verificar si todos los campos están completos
            % TES_Circuit (circuit)

            % StrLabel_On = {'TES Device';'TES Dimensions';'Circuit Values';...
            %     'IV-Curves';'Update Circuit Parameters (Slope IV-Curves)';'Import IV-Curves';...
            %     'Critical Currents';'Import Critical Currents';'Field Scan';'Import Field Scan';'Save TES Data';'Options'};
            StrLabel_On = {'TES Device';'TES Dimensions';'SQUID Temperature';'Circuit Values';'Circuit Noise';...
                'IV-Curves';'Import IV-Curves';...
                'Critical Currents';'Import Critical Currents';'Field Scan';'Import Field Scan';'Save TES Data';'Options'};
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                h.Enable = StrEnable{(~TES_ID+1)};
            end


            %   Si hay algun campo vacío (Update from IV curves)
            % TES_IVCurveSet (IVsetP o IVsetN)
            %   Si están vacios (Import IV curves)


            if Session.TES.IVsetP.Filled || Session.TES.IVsetN.Filled
                StrLabel_On = {'Center IV-Curves';'Check IV-Curves';'Save IV-Curve Set';'Fit P vs. T';...
                    'Superconductor State';'Load TF in Superconductor State (TFS)';'Check TFS';'Load Noise in Superconductor State';'Check Superconductor State Noise';...
                    'Normal State';'Load TF in Normal State (TFN)';'Check TFN';'Load Noise in Normal State';'Check Normal State Noise'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Center IV-Curves';'Check IV-Curves';'Save IV-Curve Set';'Fit P vs. T';...
                    'Superconductor State';'Load TF in Superconductor State (TFS)';'Check TFS';'Load Noise in Superconductor State';'Check Superconductor State Noise';...
                    'Normal State';'Load TF in Normal State (TFN)';'Check TFN';'Load Noise in Normal State';'Check Normal State Noise'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end

            if Session.TES.IVsetP.Filled_Imported || Session.TES.IVsetN.Filled_Imported
                StrLabel_On = {'Center IV-Curves'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Center IV-Curves'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end


            % TES_Gset (GsetP o GsetN)
            %   Si están vacios (FitPset)
            if Session.TES.GsetP.Filled || Session.TES.GsetN.Filled
                StrLabel_On = {'TES Thermal Parameters vs. %Rn';'TES Thermal Parameter Values';'Get G(T)';...
                    'Plot';'Plot NKGT Set'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'TES Thermal Parameters vs. %Rn';'TES Thermal Parameter Values';'Get G(T)';...
                    'Plot';'Plot NKGT Set'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end
            if (Session.TES.TESThermalP.Filled)||Session.TES.TESThermalN.Filled
                StrLabel_On = {'Plot';'Plot RTs Set'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Plot RTs Set'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end

            if ~isempty(Session.ZTDB.RTs4p)
                StrLabel_On = {'Plot';'Plot RT 4 points'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Plot RT 4 points'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end

            % TES_Param (TES)
            %   Si están vacios (plotNKGT)
            if (Session.TES.TESThermalP.Filled && Session.TES.TFS.Filled)
                StrLabel_On = {'Z(w)-Noise Analysis';'Fit Z(w)-Noise to ElectroThermal Model';'Re-Analyze Loaded TES'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Z(w)-Noise Analysis';'Fit Z(w)-Noise to ElectroThermal Model';'Re-Analyze Loaded TES'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end
            try
                if Session.TES.TFS.Filled && Session.TES.TFN.Filled
                    StrLabel_On = {'Z(w) Derived L'};
                    for i = 1:length(StrLabel_On)
                        h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)};
                    end
                else
                    StrLabel_Off = {'Z(w) Derived L'};
                    for i = 1:length(StrLabel_Off)
                        h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)+1};
                    end
                end
            catch
                StrLabel_Off = {'Z(w) Derived L'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end
            % TES_P (PP o PN)
            %   Si están vacios (FitZset)
            if any(Session.TES.PP.Filled) || any(Session.TES.PN.Filled)
                StrLabel_On = {'Plot';'Plot ABCT Set';'Plot Z(w) vs %Rn';'Plot Noise vs %Rn';'Plot TES Data';'Plot IV-Z'};
                for i = 1:length(StrLabel_On)
                    h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)};
                end
            else
                StrLabel_Off = {'Plot ABCT Set';'Plot Z(w) vs %Rn';'Plot Noise vs %Rn';'Plot TES Data';'Plot IV-Z'};
                for i = 1:length(StrLabel_Off)
                    h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                    h.Enable = StrEnable{(~TES_ID+1)+1};
                end
            end

            StrSummary = {'Summary';'Z(w)-Noise Viewer';'Word Graphical Report'};
            for i = 1:length(StrSummary)
                h = findobj(fig,'Text',StrSummary{i},'Tag','Analyzer');
                h.Enable = 'on';
            end


            try
                if Session.TES.IC.Filled
                    StrLabel_On = {'Plot';'Plot Critical Currents'};
                    for i = 1:length(StrLabel_On)
                        h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)};
                    end
                else
                    StrLabel_Off = {'Plot Critical Currents'};
                    for i = 1:length(StrLabel_Off)
                        h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)+1};
                    end
                end

                if Session.TES.FieldScan.Filled
                    StrLabel_On = {'Plot';'Plot Field Scan'};
                    for i = 1:length(StrLabel_On)
                        h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)};
                    end
                else
                    StrLabel_Off = {'Plot Field Scan'};
                    for i = 1:length(StrLabel_Off)
                        h = findobj(fig,'Text',StrLabel_Off{i},'Tag','Analyzer');
                        h.Enable = StrEnable{(~TES_ID+1)+1};
                    end
                end


            catch
            end


            % Enabling menus in Macro mode.
            Ok_Thermal = zeros(1,length(app.Session));
            Ok_PP = zeros(1,length(app.Session));
            Ok_IC = zeros(1,length(app.Session));
            Ok_FieldScan = zeros(1,length(app.Session));
            for i = 1:length(app.Session)
                Ok_Thermal(1,i) = or(app.Session{i}.TES.TESThermalP.Filled,app.Session{i}.TES.TESThermalN.Filled);
                Ok_PP(1,i) = any([app.Session{i}.TES.PP.Filled app.Session{i}.TES.PN.Filled]);
                Ok_IC(1,i) = app.Session{i}.TES.IC.Filled;
                Ok_FieldScan(1,i) = app.Session{i}.TES.FieldScan.Filled;
            end
            StrLabel_On = {'Plot NKGT Sets';'Plot RTs Sets'};
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                if any(Ok_Thermal)
                    h.Enable = 'on';
                else
                    h.Enable = 'off';
                end
            end
            StrLabel_On = {'Plot RTs 4 points'};

            a = 0;
            for i = 1:length(app.Session)
                a = or(a,~isempty(app.Session{i}.ZTDB.RTs4p));
            end
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                if a
                    h.Enable = 'on';
                else
                    h.Enable = 'off';
                end
            end


            StrLabel_On = {'Plot ABCT Sets';'Plot TESs Data'};
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                if any(Ok_PP)
                    h.Enable = 'on';
                else
                    h.Enable = 'off';
                end
            end

            StrLabel_On = {'Plots Critical Currents'};
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                if any(Ok_IC)
                    h.Enable = 'on';
                else
                    h.Enable = 'off';
                end
            end
            StrLabel_On = {'Plots Field Scan'};
            for i = 1:length(StrLabel_On)
                h = findobj(fig,'Text',StrLabel_On{i},'Tag','Analyzer');
                if any(Ok_FieldScan)
                    h.Enable = 'on';
                else
                    h.Enable = 'off';
                end
            end
        end

        function Enabling_TESResMenus(app)
            %% Enabling TES Resolution menus

            if isempty(app.PulseSet.pulso1)
                app.IdenKaNoise.Enable = 'off';
                app.PlotPulses.Enable = 'off';
            else
                app.IdenKaNoise.Enable = 'on';                
            end

            if isempty(app.PulseSet.indNoise)
                app.BuildOptimalFilter.Enable = 'off';
            else
                app.BuildOptimalFilter.Enable = 'on';
                app.PlotPulses.Enable = 'on';
                app.PlotKa.Enable = 'on';
                app.PlotNoise.Enable = 'on';                
            end
            if isempty(app.OF.OF)
                app.PlotOF.Enable="off";
            else
                app.PlotOF.Enable="on";
            end


            if isempty(app.PulseSet.OFT_Energy)
                app.RemoveOutliers.Enable = 'off';
                app.TESResolutionestimation.Enable = 'off';
            else
                app.RemoveOutliers.Enable = 'on';
                app.TESResolutionestimation.Enable = 'on';
            end

            if isempty(app.PulseSet.indKaNaN)
                app.ClusteringDatabyDClevel.Enable = 'off';
            else
                app.ClusteringDatabyDClevel.Enable = 'on';
                app.Plotclust.Enable='on';
            end

        end
        
        function SaveGraph(app)
            ha = findobj(app.UserData,'Type','Axes');

            fg = figure;
            copyobj(ha,fg);
            [file,path] = uiputfile('*.jpg','Save Graph name');
            if ~isequal(file,0)
                print(fg,'-djpeg',[path filesep file]);
                %     hgsave(fg,[path filesep file]);
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Analyzer.Units = 'normalized';
            position = get(app.Analyzer,'Position');
            set(app.Analyzer,'Color',[0 120 180]/255,'Position',...
                [0.5-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');
            app.Analyzer.Name = [app.Analyzer.Name '  ---  ' app.VersionStr];

            %%% Cargar la última version
            % Busca las versiones disponibles
            dv = dir([fileparts(mfilename('fullpath')) filesep 'v*']);
            % De estos tiene que seleccionar solo los directorios
            indv = [];
            dvName = {[]};
            dvNum = [];
            for i = 1:length(dv)
                if ~dv(i).isdir
                    indv(i) = i;
                else
                    dvName{i} = dv(i).name;
                    dvNum(i) = str2double(dv(i).name(2:end));
                end
            end
            dv(indv) = [];
            % handles.VersionNum = dvNum;
            % handles.VersionPath = [fileparts(mfilename('fullpath')) filesep handles.VersionStr(end-3:end)];
            %% Añadir la última version
            % addpath(handles.VersionPath);
            indx = strfind(fileparts(mfilename('fullpath')),'\');
            NameStr = fileparts(mfilename('fullpath'));
            addpath([NameStr(1:indx(end)) 'GeneralFcn'])
            %% Bloque para acceder a la base de datos %%


            StrEnable = {'on';'off'};
            StrLabel = {'Unload TES';'Re-Analyze Loaded TES';'Save TES Data';'Plot';'Macro';'Summary';'TES Device';...
                'IV-Curves';'Fit P vs. T';'TES Thermal Parameters vs. %Rn';'TES Thermal Parameter Values';'Get G(T)';...
                'Superconductor State';'Normal State';'Critical Currents';'Field Scan';'Z(w)-Noise Analysis';'Options'};
            for i = 1:length(StrLabel)
                h = findobj(app.Analyzer,'Text',StrLabel{i},'Tag','Analyzer');
                h.Enable = StrEnable{~app.TES_ID+1};
            end

            app.Save_menu = uicontextmenu(app.Analyzer);
            uimenu(app.Save_menu,'Text','Save Graph','Callback',{@SaveGraph},'UserData',app);

            app.NKGT_menu = uicontextmenu(app.Analyzer);
            uimenu(app.NKGT_menu,'Text','Show');
            uimenu(app.NKGT_menu,'Text','Error bars','Callback',{@Handle_Errors});            
            uimenu(app.NKGT_menu,'Text','Export Graphic Data','Callback',{@ExportGraph});
            uimenu(app.NKGT_menu,'Text','Save Graph','Callback',{@SaveGraph});
            uimenu(app.NKGT_menu,'Text','Link all x axes','Callback',{@ManagingAxes});
            uimenu(app.NKGT_menu,'Text','Change x axes limits','Callback',{@ManagingAxes});
            uimenu(app.NKGT_menu,'Text','Change y axes limits','Callback',{@ManagingAxes});

            app.OF = OptFilter;
            app.PulseSet = PullPulse;
            % app.Analyzer.ContextMenu = app.NKGT_menu;
            app.Analyzer.UserData = app;
            % Update handles structure
            guidata(app.Analyzer, app);
            
        end

        % Menu selected function: About, Guide
        function HelpTES(app, event)
            switch event.Source.Text
                case 'Guide'
                    winopen('TES_Analyzer_UserGuide.pdf');
                case 'About'
                    fig = figure('Visible','off','NumberTitle',...
                        'off','Name',app.VersionStr,'MenuBar','none',...
                        'Units','Normalized','Position',[0.35 0.35 0.3 0.22]);
                    d = dir('Analyzer.m');
                    %         figure(fig);

                    axtext = subplot(2,3,1,'Visible','off');
                    text(0,0.6,'Software developed by Juan Bolea for QMAD INMA-CSIC-UNIZAR.');
                    text(0,0.3,['Last update: ' d.date]);
                    text(0,0,['Current version: ' app.VersionStr]);


                    ax1 = subplot(2,3,4,'Visible','off');
                    ax2 = subplot(2,3,5,'Visible','off');
                    ax3 = subplot(2,3,6,'Visible','off');
                    try
                        axes(ax1);
                        data = imread('gatete.png');
                        image(data)
                        ax1.Visible = 'off';
                    end
                    try
                        axes(ax2);
                        data = imread('Unizar.png');
                        data(data == 0) = 255;
                        image(data)
                        ax2.Visible = 'off';
                    end
                    axes(ax3);
                    data = imread('INMALogo.jpg');
                    %         data(data == 0) = 255;
                    image(data)

                    %         data = imread('ICMA-CSIC.jpg');
                    %         image(data)
                    ax3.Visible = 'off';
                    fig.Position = [0.35 0.35 0.3 0.22];
                    fig.Visible = 'on';
                otherwise

            end
        end

        % Menu selected function: WordGraphicalReportMenu, 
        % ...and 1 other component
        function SummaryTES(app, event)
            switch event.Source.Text
                case 'Z(w)-Noise Viewer'
                    app.Session{app.TES_ID}.TES.TFNoiseViever(app);
                case 'Word Graphical Report'
                    app.Session{app.TES_ID}.TES.GraphsReport(app.Session{app.TES_ID});
                otherwise
            end
        end

        % Menu selected function: ElectroThermalModelFittingOptionsMenu, 
        % ...and 2 other components
        function OptionsTES(app, event)
            switch event.Source.Text
                case 'P vs T Model Fitting Options'
                    app.Session{app.TES_ID}.TES.PvTModel = app.Session{app.TES_ID}.TES.PvTModel.View;
                case 'Electro Thermal Model Fitting Options'
                    app.Session{app.TES_ID}.TES.ElectrThermalModel = app.Session{app.TES_ID}.TES.ElectrThermalModel.View;
                case 'Report Options'
                    app.Session{app.TES_ID}.TES.Report = app.Session{app.TES_ID}.TES.Report.View;
                otherwise
            end
        end

        % Menu selected function: PlotABCTSetsMenu, PlotIVZsMenu, 
        % ...and 6 other components
        function MacroTES(app, event)
            Markers = {'o';'x';'+';'*';'s';'d';'v';'^';'.';'<';'>';'p';'h'};
            switch event.Source.Text
                case 'Plot NKGT Sets'                    
                    [s,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    delete(indAxes);

                    colors = distinguishable_colors(length(s));
                    Lines_old = [];
                    j = 1;
                    for i = s
                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        rp = [app.Session{i}.TES.TESThermalP.Rn.Value app.Session{i}.TES.TESThermalN.Rn.Value];
                        if isempty(rp)
                            continue;
                        end
                        app.Session{i}.TES.plotNKGTset(fig,rp);
                        Lines = findobj(fig.hObject,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                Lines(Ln).UserData = i;
                                if ~strcmp(Lines(Ln).DisplayName,['Operating Point - ' SessionName])
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                    %                         Lines(Ln).Color = colors(j,:);
                                end
                            else
                                if ~isequal(Lines(Ln).UserData,i-1)
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = i;
                                    if ~strcmp(Lines(Ln).DisplayName,['Operating Point - ' SessionName])
                                        Lines(Ln).Marker = Markers{j};
                                        Lines(Ln).MarkerSize = 6;
                                        %                             Lines(Ln).Color = colors(j,:);
                                    end

                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                    end

                case 'Plot RTs Sets'

                    [s,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s)
                        return;
                    end
                    fig = app.Analyzer;
                    indAxes = findobj(fig,'Type','Axes');
                    delete(indAxes);

                    colors = distinguishable_colors(length(s));
                    Lines_old = [];
                    j = 1;
                    for i = s
                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        app.Session{i}.TES = app.Session{i}.TES.plotRTs(fig);
                        Lines = findobj(fig,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                US = Lines(Ln).UserData;
                                Lines(Ln).UserData = [US; i];
                                Lines(Ln).Marker = Markers{j};
                                Lines(Ln).MarkerSize = 6;
                                %                     Lines(Ln).Color = colors(j,:);
                            else
                                US = Lines(Ln).UserData;
                                if length(US)<5
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = [US; i];
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                    %                         Lines(Ln).Color = colors(j,:);
                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                    end

                case 'Plot RTs 4 points'
                    
                    [s,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s)
                        return;
                    end
                    fig = app.Analyzer;
                    indAxes = findobj(fig,'Type','Axes');
                    delete(indAxes);
                    for i = s
                        app.Session{i}.TES.plotRTs4points(fig,app.Session{i}.ZTDB);
                    end

                case 'Plot ABCT Sets'
                    
                    [s,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    delete(indAxes);

                    er(1:4) = {[]};
                    h_bad(1:4) = {[]};
                    erbad(1:4) = {[]};

                    colors = distinguishable_colors(length(s));
                    Lines_old = [];
                    j = 1;
                    for i = s
                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        fig = app.Session{i}.TES.plotABCT(fig);
                        Lines = findobj(fig.hObject,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                US = Lines(Ln).UserData;
                                Lines(Ln).UserData = [US; i];
                                Lines(Ln).Marker = Markers{j};
                                Lines(Ln).MarkerSize = 6;
                                %                     Lines(Ln).Color = colors(j,:);
                            else
                                US = Lines(Ln).UserData;
                                if length(US)<5
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = [US; i];
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                    %                         Lines(Ln).Color = colors(j,:);
                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                        Axes = findobj(fig.hObject,'Type','Axes');
                        for k = 1:length(Axes)
                            USData{k} = Axes(k).UserData;
                            try
                                er{k} = [er{k} USData{k}.er];
                                h_bad{k} = [h_bad{k} USData{k}.h_bad];
                                erbad{k} = [erbad{k} USData{k}.erbad];

                                data{k}.er = er{k};
                                data{k}.h_bad = h_bad{k};
                                data{k}.erbad = erbad{k};
                                Axes(k).UserData = data{k};
                            catch
                            end
                        end
                    end
                    for k = 1:length(Axes)
                        Axes(k).UserData = data{k};
                    end

                case 'Plot TESs Data'

                    
                    [s1,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s1)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    pause(0.2)
                    delete(indAxes);


                    str = fieldnames(app.Session{ app.TES_ID}.TES.PP(1).p);
                    dummy = uimenu('Visible','off');
                    waitfor(GraphicTESData(str,dummy));
                    data = dummy.UserData;
                   
                    if ~isempty(data)

                        switch data.case
                            case 1 % vs. Rn

                                TbathNums = [];
                                for i = s1
                                    TbathNums = [TbathNums [app.Session{i}.TES.PP.Tbath] ...
                                        [app.Session{i}.TES.PN.Tbath]];
                                end

                                str = cellstr(num2str(unique(TbathNums)'));
                                [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                                    'SelectionMode','multiple',...
                                    'ListString',str);
                                if isempty(s)
                                    return;
                                end
                                Tbath_ref = str2double(str(s))';
                                Rn = [];

                                %                     app.Session{app.TES_ID}.TES.PlotTESData(data.param1,[],Tbath,app.Analyzer);

                                %                     colors = distinguishable_colors(length(s1));
                                Lines_old = [];
                                j = 1;
                                for i = s1
                                    TbathNums = unique([[app.Session{i}.TES.PP.Tbath] ...
                                        [app.Session{i}.TES.PN.Tbath]]);
                                    Tbath = intersect(TbathNums,Tbath_ref);
                                    if isempty(Tbath)
                                        continue;
                                    end
                                    SessionName = app.Session{i}.Tag;
                                    SessionName(SessionName == '_') = ' ';
                                    app.Session{i}.TES.PlotTESData(data.param1,Rn,Tbath,app.Analyzer);
                                    Lines = findobj(fig.hObject,'Type','Line');
                                    Lines = setdiff(Lines,Lines_old);

                                    jp = [];
                                    jn = [];
                                    for k = 1:length(Lines)
                                        Tag = [Lines(k).DisplayName];
                                        if contains(Tag,'Positive')
                                            TbathP(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jp = [jp k];
                                        end
                                        if contains(Tag,'Negative')
                                            TbathN(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jn = [jn k];
                                        end
                                    end
                                    LinesP = Lines(jp);
                                    [~,ind] = sort(TbathP(jp));
                                    LinesP = LinesP(ind);
                                    LinesN = Lines(jn);
                                    [~,ind] = sort(TbathN(jn));
                                    LinesN = LinesN(ind);
                                    Lines = [LinesP;LinesN];

                                    colors = distinguishable_colors(length(Lines));

                                    for Ln = 1:length(Lines)
                                        if i == s1(1)
                                            Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                            Lines(Ln).UserData = i;
                                            Lines(Ln).Color = colors(Ln,:);
                                            Lines(Ln).Marker = Markers{j};
                                            Lines(Ln).MarkerSize = 6;
                                        else
                                            if ~isequal(Lines(Ln).UserData,i-1)
                                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                                Lines(Ln).UserData = i;
                                                Lines(Ln).Color = colors(Ln,:);
                                                Lines(Ln).Marker = Markers{j};
                                                Lines(Ln).MarkerSize = 6;
                                            end
                                        end
                                    end
                                    Lines_old = [Lines_old;Lines];
                                    j = j+1;
                                end

                            case 2 % vs. Tbath
                                prompt = {'Enter the %Rn range:'};
                                name = '%Rn range (0 < %Rn < 1)';
                                numlines = [1 70];
                                defaultanswer = {'0.5:0.05:0.8'};
                                answer = inputdlg(prompt,name,numlines,defaultanswer);
                                Rn = eval(['[' answer{1} ']']);
                                Tbath = [];

                                %                     colors = distinguishable_colors(length(s1));
                                Lines_old = [];
                                j = 1;
                                for i = s1
                                    SessionName = app.Session{i}.Tag;
                                    SessionName(SessionName == '_') = ' ';
                                    app.Session{i}.TES.PlotTESData(data.param1,Rn,Tbath,app.Analyzer);
                                    Lines = findobj(fig.hObject,'Type','Line');
                                    Lines = setdiff(Lines,Lines_old);

                                    jp = [];
                                    jn = [];
                                    for k = 1:length(Lines)
                                        Tag = [Lines(k).DisplayName];
                                        if contains(Tag,'Positive')
                                            TbathP(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jp = [jp k];
                                        end
                                        if contains(Tag,'Negative')
                                            TbathN(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jn = [jn k];
                                        end
                                    end
                                    LinesP = Lines(jp);
                                    [~,ind] = sort(TbathP(jp));
                                    LinesP = LinesP(ind);
                                    LinesN = Lines(jn);
                                    [~,ind] = sort(TbathN(jn));
                                    LinesN = LinesN(ind);
                                    Lines = [LinesP;LinesN];

                                    colors = distinguishable_colors(length(Lines));

                                    for Ln = 1:length(Lines)
                                        if i == s1(1)
                                            Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                            Lines(Ln).UserData = i;
                                            Lines(Ln).Color = colors(Ln,:);
                                            Lines(Ln).Marker = Markers{j};
                                            Lines(Ln).MarkerSize = 6;
                                        else
                                            if ~isequal(Lines(Ln).UserData,i-1)
                                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                                Lines(Ln).UserData = i;
                                                Lines(Ln).Color = colors(Ln,:);
                                                Lines(Ln).Marker = Markers{j};
                                                Lines(Ln).MarkerSize = 6;
                                            end
                                        end
                                    end
                                    Lines_old = [Lines_old;Lines];
                                    j = j+1;
                                end

                            case 3 % Param1 vs. Param2
                                param = data.param1;
                                param2 = data.param2;
                                params = char([{param};{param2}]);
                                Lines_old = [];
                                j = 1;
                                for i = s1
                                    SessionName = app.Session{i}.Tag;
                                    SessionName(SessionName == '_') = ' ';
                                    app.Session{i}.TES.PlotTESData(params,[],[],app.Analyzer);
                                    Lines = findobj(fig.hObject,'Type','Line');
                                    Lines = setdiff(Lines,Lines_old);

                                    jp = [];
                                    jn = [];
                                    for k = 1:length(Lines)
                                        Tag = [Lines(k).DisplayName];
                                        if contains(Tag,'Positive')
                                            TbathP(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jp = [jp k];
                                        end
                                        if contains(Tag,'Negative')
                                            TbathN(k) = sscanf(Tag(strfind(Tag,':')+1:strfind(Tag,'mK')+1),'%d mK ');
                                            jn = [jn k];
                                        end
                                    end
                                    LinesP = Lines(jp);
                                    [~,ind] = sort(TbathP(jp));
                                    LinesP = LinesP(ind);
                                    LinesN = Lines(jn);
                                    [~,ind] = sort(TbathN(jn));
                                    LinesN = LinesN(ind);
                                    Lines = [LinesP;LinesN];

                                    colors = distinguishable_colors(length(Lines));

                                    for Ln = 1:length(Lines)
                                        if i == s1(1)
                                            Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                            Lines(Ln).UserData = i;
                                            Lines(Ln).Color = colors(Ln,:);
                                            Lines(Ln).Marker = Markers{j};
                                            Lines(Ln).MarkerSize = 6;
                                        else
                                            if ~isequal(Lines(Ln).UserData,i-1)
                                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                                Lines(Ln).UserData = i;
                                                Lines(Ln).Color = colors(Ln,:);
                                                Lines(Ln).Marker = Markers{j};
                                                Lines(Ln).MarkerSize = 6;
                                            end
                                        end
                                    end
                                    Lines_old = [Lines_old;Lines];
                                    j = j+1;
                                end
                        end
                    else
                        return;
                    end
                    

                case 'Plot IV-Zs'
                    
                    [s1,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s1)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    delete(indAxes);
                    Lines_old = [];

                    TbathNums = [];
                    for i = s1
                        TbathNums = [TbathNums [app.Session{i}.TES.PP.Tbath] ...
                            [app.Session{i}.TES.PN.Tbath]];
                    end

                    str = cellstr(num2str(unique(TbathNums)'));
                    [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                        'SelectionMode','multiple',...
                        'ListString',str);
                    if isempty(s)
                        return;
                    end
                    Tbath_ref = str2double(str(s))';

                    j = 1;
                    for i = s1
                        TbathNums = unique([[app.Session{i}.TES.PP.Tbath] ...
                            [app.Session{i}.TES.PN.Tbath]]);
                        Tbath = intersect(TbathNums,Tbath_ref);

                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        fig = app.Session{i}.TES.CompareIV_Z(app.Session{i}.TES.IVsetP,app.Session{i}.TES.PP,Tbath,fig);
                        app.Session{i}.TES.CompareIV_Z(app.Session{app.TES_ID}.TES.IVsetN,app.Session{app.TES_ID}.TES.PN,Tbath,fig);

                        Lines = findobj(fig.hObject,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s1(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                Lines(Ln).UserData = i;
                                %                                 Lines(Ln).Color = colors(j,:);
                                Lines(Ln).Marker = Markers{j};
                                Lines(Ln).MarkerSize = 6;
                            else
                                if ~isequal(Lines(Ln).UserData,i-1)
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = i;
                                    %                                     Lines(Ln).Color = colors(j,:);
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                    end

                case 'Plots Critical Currents'
                    
                    [s1,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s1)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    delete(indAxes);
                    Lines_old = [];
                    j = 1;
                    for i = s1
                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        app.Session{i}.TES.PlotCriticalCurrent(app.Analyzer);
                        Lines = findobj(fig.hObject,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s1(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                Lines(Ln).UserData = i;
                                %                                 Lines(Ln).Color = colors(j,:);
                                Lines(Ln).Marker = Markers{j};
                                Lines(Ln).MarkerSize = 6;
                            else
                                if ~isequal(Lines(Ln).UserData,i-1)
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = i;
                                    %                                     Lines(Ln).Color = colors(j,:);
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                    end

                case 'Plots Field Scan'

                    [s1,~] = listdlg('PromptString','Select Loaded TES:',...
                        'SelectionMode','multiple',...
                        'ListString',app.Loaded_TES.Items);
                    if isempty(s1)
                        return;
                    end
                    fig.hObject = app.Analyzer;
                    indAxes = findobj(fig.hObject,'Type','Axes');
                    delete(indAxes);
                    Lines_old = [];
                    j = 1;
                    for i = s1
                        SessionName = app.Session{i}.Tag;
                        SessionName(SessionName == '_') = ' ';
                        app.Session{i}.TES.PlotFieldScan(app.Analyzer);
                        Lines = findobj(fig.hObject,'Type','Line');
                        Lines = setdiff(Lines,Lines_old);
                        for Ln = 1:length(Lines)
                            if i == s1(1)
                                Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                Lines(Ln).UserData = i;
                                %                                 Lines(Ln).Color = colors(j,:);
                                Lines(Ln).Marker = Markers{j};
                                Lines(Ln).MarkerSize = 6;
                            else
                                if ~isequal(Lines(Ln).UserData,i-1)
                                    Lines(Ln).DisplayName = [Lines(Ln).DisplayName ' - ' SessionName];
                                    Lines(Ln).UserData = i;
                                    %                                     Lines(Ln).Color = colors(j,:);
                                    Lines(Ln).Marker = Markers{j};
                                    Lines(Ln).MarkerSize = 6;
                                end
                            end
                        end
                        Lines_old = [Lines_old;Lines];
                        j = j+1;
                    end

                otherwise
            end
            figure(app.Analyzer);
        end

        % Value changed function: Loaded_TES
        function Loaded_TES_Callback(app, event)
            app.TES_ID = app.Loaded_TES.ValueIndex;
            indAxes = findobj(app.Analyzer,'Type','Axes');
            delete(indAxes);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
        end

        % Callback function
        function Print_Callback(app, event)
            
        end

        % Menu selected function: TESDataMenu
        function TESData(app, event)
            switch event.Source.Text                                  

                case 'Re-Analyze Loaded TES'                                                

                otherwise
            end
        end

        % Menu selected function: LoadTESMenu
        function LoadTES(app, event)

            StrLabel = {'TES Data';'Help';'Guide';'About'};
            for i = 1:length(StrLabel)
                h = findobj(app.Analyzer,'Text',StrLabel{i},'Tag','Analyzer');
                h.Enable = 'on';
            end
            indAxes = findobj(app.Analyzer,'Type','Axes','Tag','Analyzer');
            delete(indAxes);

            obj = TES_Analyzer_Session;
            Sess = obj.LoadTES(app);
            if isa(Sess,'TES_Analyzer_Session')
                % Comprobar si ya esta cargado
                if app.TES_ID ~= 0
                    for i = 1:length(app.TES_ID)
                        if strcmp(Sess.File,app.Session{i}.File) && strcmp(Sess.Path,app.Session{i}.Path)
                            msgbox('Selected TES is already loaded',app.VersionStr);
                            return;
                        end
                    end
                end
                if isempty(app.Loaded_TES.Value)
                    app.TES_ID = app.TES_ID+1;
                else
                    app.TES_ID = size(app.Loaded_TES.Items,2)+1;
                end

                Sess.ID = app.TES_ID;
                app.Session{app.TES_ID} = Sess;
                for i = 1:app.TES_ID
                    ListStr(i) = {app.Session{i}.Tag};
                end
                
                app.Loaded_TES.Items = ListStr;
                app.Loaded_TES.Value = ListStr{app.TES_ID};
                app.Loaded_TES.Visible = 'on';
                app.LoadedTESLabel.Visible = 'on';


                Enabling(app, Sess,app.TES_ID,app.Analyzer);
                if length(app.Session) > 1
                    h = findobj(app.Analyzer,'Text','Macro','Tag','Analyzer');
                    h.Enable = 'on';
                end
                app.UnloadTESMenu.Enable = 'on'; % UnloadTES
                Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            end

        end

        % Menu selected function: SetDataPathMenu
        function SetDataPath(app, event)

            try
                DataPath = uigetdir(app.Session{app.TES_ID(end)}.Path, 'Pick a Data path named Z(w)-Ruido');
            catch
                DataPath = uigetdir('', 'Pick a Data path named Z(w)-Ruido');
            end
            if DataPath ~= 0
                DataPath = [DataPath filesep];
            else
                errordlg('Invalid Data path name!',app.VersionStr,'modal');
                return;
            end

            h = findobj(app.Analyzer,'Type','uimenu','Tag','Analyzer');
            for i = 1:length(h)
                h(i).Enable = 'off';
            end

            StrLabel = {'TES Data';'Load TES';'TES Analysis';'Unload TES';'Set Data Path';'Critical Currents';'Import Critical Currents';'Field Scan';'Import Field Scan';...
                'Options';'P vs T Model Fitting Options';'Electro Thermal Model Fitting Options';'Report Options';'Help';'Guide';'About'};
            for i = 1:length(StrLabel)
                h = findobj(app.Analyzer,'Text',StrLabel{i},'Tag','Analyzer');
                h.Enable = 'on';
            end
            indAxes = findobj(app.Analyzer,'Type','Axes','Tag','Analyzer');
            delete(indAxes);

            Session = TES_Analyzer_Session;
            Session.File = [];
            Session.Path = DataPath;
            Session.ZTDB = Session.ZTDB.Constructor(app.DataBaseName,app.Username,app.Password);

            Indx = find(DataPath(1:end-1) == filesep,3,'last');
            if Indx < 3
                YearStr = '';
                MonthStr = '';
                RunStr = '';
            end
            YearStr = DataPath(Indx(1)+1:Indx(2)-1);
            MonthStr = DataPath(Indx(2)+1:Indx(3)-1);
            RunStr = DataPath(Indx(3)+1:end-1);

            NickName = '';
            try
                % Conectando con la base de datos en busca de algún parámetro para
                % cargar
                Session.ZTDB = Session.ZTDB.UpdateFromExcel(Session);
                % De aquí pueden salir los valores de Rsh, L, invMf y invMin (Por
                % ahora no)

                try
                    NickName = [RunStr '_' MonthStr '_' YearStr '_' Session.ZTDB.TES_Idn];
                catch
                    NickName = [RunStr '_' MonthStr '_' YearStr];
                end
            catch
            end


            answer = inputdlg({'Insert a Nick name for the TES'},app.VersionStr,[1 50],{NickName});
            if isempty(answer)
                errordlg('Invalid Nick name!',app.VersionStr,'modal');
                return;
            end
            Session.Tag = answer{1};
            if isempty(app.Loaded_TES.ItemsData)
                app.TES_ID = app.TES_ID+1;
            else
                app.TES_ID = size(app.Loaded_TES.ItemsData,2)+1;
            end
            Session.ID = app.TES_ID;
            app.Session{app.TES_ID} = Session;
            for i = 1:app.TES_ID
                ListStr(i) = {app.Session{i}.Tag};
            end

            
            app.Loaded_TES.Items = ListStr;
            app.Loaded_TES.Value = ListStr{app.TES_ID};
            app.LoadedTESLabel.Visible = 'on';
            app.Loaded_TES.Visible = 'on';

            app.Session{app.TES_ID}.TES = TES_Struct;
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.Constructor;

            app.Session{app.TES_ID}.NickName = NickName;
            [ZTDB, Session] = app.Session{app.TES_ID}.ZTDB.Update(app.Session{app.TES_ID});
            Session.ZTDB = ZTDB;
            app.Session{app.TES_ID} = Session;
            % Searching for Circuir variable (inside session or in circuit)
            [filename, pathname] = uigetfile({'sesion*';'circuit*'}, 'Pick a MATLAB file refering to circuit values',[Session.Path 'circuit.mat']);
            if ~isequal(filename,0)
                switch filename
                    case 'sesion.mat'
                        load([pathname filename],'circuit');
                    case 'circuit.mat'
                        a = load([pathname filename],'circuit*');
                        circuit = eval(['a.' char(fieldnames(a))]);
                        %                     load([pathname filename],'circuit');
                        % Robustecer para buscar la variable circuit
                    otherwise
                        a = load([pathname filename],'circuit*');
                        circuit = eval(['a.' char(fieldnames(a))]);
                end
                app.Session{app.TES_ID}.TES.circuit = app.Session{app.TES_ID}.TES.circuit.Update(circuit);
                app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.CheckCircuit;

                app.Session{app.TES_ID}.TES.circuitNoise = app.Session{app.TES_ID}.TES.circuitNoise.View(app.Session{app.TES_ID}.TES);

                if isempty(app.Session{app.TES_ID}.TES.circuitNoise.Array)
                    app.Session{app.TES_ID}.TES.circuit.Nsquid.Value = app.Session{app.TES_ID}.TES.circuitNoise.Value;
                else
                    app.Session{app.TES_ID}.TES.circuit.Nsquid.Value = app.Session{app.TES_ID}.TES.circuitNoise.Array;
                end

                [~, app.Session{app.TES_ID}] = app.Session{app.TES_ID}.ZTDB.UpdateTESdimsFromExcel(app.Session{app.TES_ID});
                %             app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.CheckCircuit;
                % Actualiza las dimensiones del TES en caso de que no estén en
                % la base de datos

                % Enabling(app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

                %


            else
                warndlg('Caution! Circuit parameters were not loaded, check it manually',app.VersionStr);
            end
            % guidata(app.Analyzer,app);
            %         StrLabel = {'Macro';'Plot NKGT Sets';'Plot RTs Sets';'Plot ABCT Sets';'Plot TESs Data';'Plot Critical Currents';'Plot Field Scan'};
            if length(app.Session) > 1
                %             for i = 1:length(StrLabel)
                h = findobj(app.Analyzer,'Text','Macro','Tag','Analyzer');
                h.Enable = 'on';
                %             end
            end
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);

        end

        % Menu selected function: TESDimensionsMenu
        function TES_Dimensions(app, event)
            app.Session{app.TES_ID}.TES.TESDim = app.Session{app.TES_ID}.TES.TESDim.EnterDimensions;
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
        end

        % Callback function
        function TES_Parameters(app, event)
            if isempty(app.Session{ app.TES_ID}.TES.TESParamP.Rn.Value)
                 app.Session{ app.TES_ID}.TES.TESParamP =  app.Session{ app.TES_ID}.TES.TESParamP.RnRparCalc( app.Session{ app.TES_ID}.TES.circuit);
            end
             app.Session{ app.TES_ID}.TES.TESParamP.CheckValues('Positive Ibias');
            if isempty( app.Session{ app.TES_ID}.TES.TESParamN.Rn.Value)
                 app.Session{ app.TES_ID}.TES.TESParamN =  app.Session{ app.TES_ID}.TES.TESParamN.RnRparCalc( app.Session{ app.TES_ID}.TES.circuit);
            end
             app.Session{ app.TES_ID}.TES.TESParamN.CheckValues('Negative Ibias');
            Enabling(app, app.Session{ app.TES_ID}, app.TES_ID, app.Analyzer);
            figure(app.Analyzer);
        end

        % Menu selected function: SQUIDTemperatureMenu
        function SQUID_Temperature(app, event)
            waitfor(msgbox(['Noise derived SQUID Temp: ' num2str( app.Session{app.TES_ID}.TES.SQUIDTemp*1e3) ' mK'],'Analyzer Info'));
            figure(app.Analyzer);
        end

        % Menu selected function: CircuitValuesMenu
        function Circuit_Values(app, event)
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.CheckCircuit;
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
        end

        % Menu selected function: CircuitNoiseMenu
        function Circuit_Noise(app, event)
            app.Session{app.TES_ID}.TES.circuitNoise = app.Session{app.TES_ID}.TES.circuitNoise.View(app.Session{app.TES_ID}.TES);
            app.Session{app.TES_ID}.TES.circuit = app.Session{app.TES_ID}.TES.circuit.Update(app.Session{app.TES_ID}.TES.circuitNoise);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
        end

        % Menu selected function: ImportIVCurvesMenu
        function ImportIVCurves(app, event)
            
            app.Session{app.TES_ID}.TES.IVsetP = TES_IVCurveSet;
            app.Session{app.TES_ID}.TES.IVsetP = app.Session{app.TES_ID}.TES.IVsetP.Constructor;
            app.Session{app.TES_ID}.TES.IVsetN = TES_IVCurveSet;
            app.Session{app.TES_ID}.TES.IVsetN = app.Session{app.TES_ID}.TES.IVsetN.Constructor(1);

            % En este punto se tiene que preguntar al usuario de dónde quiere
            % cargar el set de curvas IV (de los archivos originales o de una
            % estructura.

            [IVsetP, TempLims, TESP] = app.Session{app.TES_ID}.TES.IVsetP.ImportFromFiles(app.Session{app.TES_ID}.TES,app.Session{app.TES_ID}.Path);
            if isempty(TempLims)
                return;
            end
            app.Session{app.TES_ID}.TES.circuit = app.Session{app.TES_ID}.TES.circuit.Update(TESP.circuit);
            app.Session{app.TES_ID}.TES.IVsetP = app.Session{app.TES_ID}.TES.IVsetP.Update(IVsetP);

            [IVsetN, ~, TESN] = app.Session{app.TES_ID}.TES.IVsetN.ImportFromFiles(app.Session{app.TES_ID}.TES,app.Session{app.TES_ID}.TES.IVsetP(1).IVsetPath, TempLims);
            app.Session{app.TES_ID}.TES.circuit = app.Session{app.TES_ID}.TES.circuit.Update(TESN.circuit);
            app.Session{app.TES_ID}.TES.IVsetN = app.Session{app.TES_ID}.TES.IVsetN.Update(IVsetN);
            if isempty(app.Session{app.TES_ID}.TES.TESParamP.Rn.Value)
                app.Session{app.TES_ID}.TES.TESParamP = app.Session{app.TES_ID}.TES.TESParamP.RnRparCalc(app.Session{app.TES_ID}.TES.circuit);
            end
            if isempty(app.Session{app.TES_ID}.TES.TESParamN.Rn.Value)
                app.Session{app.TES_ID}.TES.TESParamN = app.Session{app.TES_ID}.TES.TESParamN.RnRparCalc(app.Session{app.TES_ID}.TES.circuit);
            end

            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to continue Centering IV Curves?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    app.CenterIVCurves;
            end            

        end

        % Menu selected function: CenterIVCurvesMenu
        function CenterIVCurves(app, event)

            [app.Session{app.TES_ID}.TES.IVsetP,TESP, UserCancel] = app.Session{app.TES_ID}.TES.IVsetP.IV_correction_methods(app.Session{app.TES_ID}.TES);
            if UserCancel
                return;
            end
            app.Session{app.TES_ID}.TES.TESParamP = app.Session{app.TES_ID}.TES.TESParamP.Update(TESP.TESParamP);

            [app.Session{app.TES_ID}.TES.IVsetN,TESN] = app.Session{app.TES_ID}.TES.IVsetN.IV_correction_methods(app.Session{app.TES_ID}.TES);
            if UserCancel
                return;
            end
            app.Session{app.TES_ID}.TES.TESParamN = app.Session{app.TES_ID}.TES.TESParamN.Update(TESN.TESParamN);

            app.Session{app.TES_ID}.TES.circuit = app.Session{app.TES_ID}.TES.circuit.Update(TESP.circuit);

            figure(app.Analyzer)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.CheckIVCurvesVisually(fig);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

            % %% Darle continuidad al analisis
            % ButtonName = questdlg('Do you want to continue Checking validity of IV Curves?', ...
            %     app.VersionStr, ...
            %     'Yes', 'No', 'Yes');
            % switch ButtonName
            %     case 'Yes'
            %         app.CheckIVCurves;
            % end   

        end

        % Window button down function: Analyzer
        function AnalyzerWindowButtonDown(app, event)


        end

        % Menu selected function: CheckIVCurvesMenu
        function CheckIVCurves(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.CheckIVCurvesVisually(fig);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to continue Fitting P versus Tbath?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    app.FitPvsT;
            end   
            
        end

        % Menu selected function: SaveIVCurveSetMenu
        function SaveIVCurveSet(app, event)
            fldnames = {'ibias';'vout';'Rtes';'rtes';'ites';'vtes';'ptes';'good';'Tbath'};
            
            for j = 1:size(app.Session{app.TES_ID}.TES.IVsetP,2)
                for i = 1:length(fldnames)
                    IVsetCorrected(j).(fldnames{i}) = app.Session{app.TES_ID}.TES.IVsetP(j).(fldnames{i});
                    % eval(['IVsetCorrected(j).' fldnames{i} '= app.Session{app.TES_ID}.TES.IVsetP(j).' fldnames{i} ';']);
                end
            end
            [filename, pathname] = uiputfile([app.Session{app.TES_ID}.TES.IVsetP(1).IVsetPath '*.mat'], 'Save Positive IV-Curve Set');
            if isequal(filename,0) || isequal(pathname,0)
                warndlg('User pressed cancel')
                return;
            end

            save(fullfile(pathname, filename),'IVsetCorrected');

            for j = 1:size(app.Session{app.TES_ID}.TES.IVsetN,2)
                for i = 1:length(fldnames)
                    IVsetCorrectedN(j).(fldnames{i}) = app.Session{app.TES_ID}.TES.IVsetN(j).(fldnames{i});
                    % eval(['IVsetCorrectedN(j).' fldnames{i} '= app.Session{app.TES_ID}.TES.IVsetN(j).' fldnames{i} ';']);
                end
            end
            [filename, pathname] = uiputfile([app.Session{app.TES_ID}.TES.IVsetN(1).IVsetPath '*.mat'], 'Save Negative IV-Curve Set');
            if isequal(filename,0) || isequal(pathname,0)
                warndlg('User pressed cancel')
                return;
            end
            save(fullfile(pathname, filename),'IVsetCorrectedN');
        end

        % Menu selected function: FitPvsTMenu
        function FitPvsT(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.fitPvsTset([],fig.hObject);

            waitfor(msgbox('Continue to Thermal parameters vs %Rn',app.VersionStr)); %app.VersionStr
            figure(app.Analyzer);
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.plotNKGTset(fig);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to loading Superconducting TF?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    obj.Source = findobj(fig.hObject,'Type','uimenu','Text','Load TF in Superconductor State (TFS)');
                    app.LoadTFinSuperconductorStateTFS(obj);
            end   
        end

        % Menu selected function: TESThermalParametersvsRnMenu
        function TESThermalParametersvsRn(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.plotNKGTset(fig);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: TESThermalParameterValuesMenu
        function TESThermalParameterValues(app, event)
            app.Session{app.TES_ID}.TES.TESThermalP.CheckValues('PosIbias');
            app.Session{app.TES_ID}.TES.TESThermalN.CheckValues('NegIbias');
        end

        % Menu selected function: GetGTMenu
        function GetGT(app, event)
            app.Session{app.TES_ID}.TES.TESThermalP.G_calc;
            app.Session{app.TES_ID}.TES.TESThermalN.G_calc;
        end

        % Menu selected function: LoadTFinSuperconductorStateTFSMenu
        function LoadTFinSuperconductorStateTFS(app, event)
            
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.TFS = app.Session{app.TES_ID}.TES.TFS.TFfromFile(app.Session{app.TES_ID}.Path,app.Analyzer,event.Source);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to loading TF in Normal State?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    obj = findobj(fig,'Type','uimenu','Text','Load TF in Normal State (TFN)');
                    app.LoadTFinNormalStateTFN(obj);
            end  

        end

        % Menu selected function: CheckTFSMenu
        function CheckTFS(app, event)

            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.TFS = app.Session{app.TES_ID}.TES.TFS.PlotTF(app.Analyzer);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

        end

        % Menu selected function: LoadNoiseinSuperconductorStateMenu
        function LoadNoiseinSuperconductorState(app, event)

            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            [filename, pathname] = uigetfile({'*.txt'}, 'Pick a Noise file refering to normal state',[app.Session{app.TES_ID}.Path 'SNoise*.txt']);
            if isequal(filename,0)
                warndlg('No file selected',app.VersionStr);
                return;
            end
            FileName = [pathname filename];
            if ~isa(app.Session{app.TES_ID}.TES.NoiseS,'TES_BasalNoises')
                app.Session{app.TES_ID}.TES.NoiseS = TES_BasalNoises;
                app.Session{app.TES_ID}.TES.NoiseS = app.Session{app.TES_ID}.TES.NoiseS.Constructor;
            end
            app.Session{app.TES_ID}.TES.NoiseS = app.Session{app.TES_ID}.TES.NoiseS.NoisefromFile(FileName,fig,app.Session{app.TES_ID}.TES);
            [app.Session{app.TES_ID}.TES.NoiseS, ~, SQUIDTemp] = app.Session{app.TES_ID}.TES.NoiseS.Plot(fig,app.Session{app.TES_ID}.TES,'Superconductor');
            app.Session{app.TES_ID}.TES.SQUIDTemp = SQUIDTemp;
            waitfor(msgbox(['Noise derived SQUID Temp: ' num2str(app.Session{app.TES_ID}.TES.SQUIDTemp*1e3) ' mK'],'Analyzer Info'));            

            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
            guidata(app.Analyzer,app)

        end

        % Menu selected function: CheckSuperconductorStateNoiseMenu
        function CheckSuperconductorStateNoise(app, event)
            if app.Session{app.TES_ID}.TES.NoiseS.Filled
                fig = app.Analyzer;
                indAxes = findobj(fig,'Type','Axes');
                delete(indAxes);
                [app.Session{app.TES_ID}.TES.NoiseS, ~, SQUIDTemp] = app.Session{app.TES_ID}.TES.NoiseS.Plot(fig,app.Session{app.TES_ID}.TES,'Superconductor');
                app.Session{app.TES_ID}.TES.SQUIDTemp = SQUIDTemp;
                waitfor(msgbox(['Noise derived SQUID Temp: ' num2str(app.Session{app.TES_ID}.TES.SQUIDTemp*1e3) ' mK'],'Analyzer Info'));
            else
                waitfor(msgbox('No file previously loaded',app.VersionStr));
            end

        end

        % Menu selected function: LoadTFinNormalStateTFNMenu
        function LoadTFinNormalStateTFN(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            if ~isa(app.Session{app.TES_ID}.TES.TFN,'TES_TFN')
                app.Session{app.TES_ID}.TES.TFN = TES_TFS;
                app.Session{app.TES_ID}.TES.TFN = app.Session{app.TES_ID}.TES.TFN.Constructor;
            end
            app.Session{app.TES_ID}.TES.TFN = app.Session{app.TES_ID}.TES.TFN.TFfromFile(app.Session{app.TES_ID}.Path,app.Analyzer,app.LoadTFinNormalStateTFNMenu);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to continue estimating L from Zw''s', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    app.ZwDerivedL;
            end  
            guidata(app.Analyzer,app)
        end

        % Menu selected function: CheckTFNMenu
        function CheckTFN(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.TFN = app.Session{app.TES_ID}.TES.TFN.PlotTF(app.Analyzer);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: LoadNoiseinNormalStateMenu
        function LoadNoiseinNormalState(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            [filename, pathname] = uigetfile({'*.txt'}, 'Pick a Noise file refering to normal state',[app.Session{app.TES_ID}.Path 'NNoise*.txt']);
            if isequal(filename,0)
                warndlg('No file selected',app.VersionStr);
                return;
            end
            FileName = [pathname filename];
            if ~isa(app.Session{app.TES_ID}.TES.NoiseN,'TES_BasalNoises')
                app.Session{app.TES_ID}.TES.NoiseN = TES_BasalNoises;
                app.Session{app.TES_ID}.TES.NoiseN = app.Session{app.TES_ID}.TES.NoiseN.Constructor;
            end
            app.Session{app.TES_ID}.TES.NoiseN = app.Session{app.TES_ID}.TES.NoiseN.NoisefromFile(FileName,fig,app.Session{app.TES_ID}.TES);


            app.Session{app.TES_ID}.TES.NoiseN = app.Session{app.TES_ID}.TES.NoiseN.Plot(fig,app.Session{app.TES_ID}.TES,'Normal');
            ButtonName = questdlg('Do you want to assign loaded normal noise to electrical noise?', ...
                'Analyzer', ...
                'No', 'Yes', 'No');
            switch ButtonName
                case 'Yes'
                    app.Session{app.TES_ID}.TES.circuit.Nsquid.Value = app.Session{app.TES_ID}.TES.NoiseN.SigNoise*1e-12;
                case 'No'
                    %                 app.Session{app.TES_ID}.TES.circuit.Nsquid.Value = 3e-12;
                otherwise
            end % switch

            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);
            guidata(app.Analyzer,app)
        end

        % Menu selected function: CheckNormalStateNoiseMenu
        function CheckNormalStateNoise(app, event)
            if app.Session{app.TES_ID}.TES.NoiseN.Filled
                fig = app.Analyzer;
                indAxes = findobj(fig,'Type','Axes');
                delete(indAxes);
                app.Session{app.TES_ID}.TES.NoiseN = app.Session{app.TES_ID}.TES.NoiseN.Plot(fig,app.Session{app.TES_ID}.TES,'Normal');
            else
                waitfor(msgbox('No file previously loaded',app.VersionStr));
            end
        end

        % Menu selected function: ZwDerivedLMenu
        function ZwDerivedL(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            L = app.Session{app.TES_ID}.TES.fitLcircuit;

            ax = axes(fig);
            semilogx(ax,app.Session{app.TES_ID}.TES.TFS.f,...
                imag(app.Session{app.TES_ID}.TES.TFS.tf./app.Session{app.TES_ID}.TES.TFN.tf),'DisplayName','Experimental Data')
            hold(ax,'on');
            grid(ax,'on');
            semilogx(ax,app.Session{app.TES_ID}.TES.TFS.f,...
                fitLfcn(L,app.Session{app.TES_ID}.TES.TFS.f,app.Session{app.TES_ID}.TES),'.-r','DisplayName','Fitting Data')

            xlabel(ax,'Freq (Hz)','FontUnits','normalized','FontSize',0.08,'FontWeight','normal');
            ylabel(ax,'Imag(TFS/TFN)','FontUnits','normalized','FontSize',0.08,'FontWeight','normal');
            set(ax,'FontUnits','normalized','FontSize',0.08,'FontWeight','normal')
            ax.Parent = fig;
            ButtonName = questdlg(['Estimation of L: ' num2str(L) ', do you want to update L value to circuit?'], ...
                app.VersionStr, ...
                'Yes', 'No', 'No');
            switch ButtonName
                case 'Yes'
                    app.Session{app.TES_ID}.TES.circuit.L.Value = L;
                    waitfor(msgbox('Parameter L updated',app.VersionStr));
            end % switch
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            figure(app.Analyzer);

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to continue loading Superconducting State Noise?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    app.LoadNoiseinSuperconductorState;
            end  

            %% Darle continuidad al analisis
            ButtonName = questdlg('Do you want to continue loading Normal State Noise?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'Yes'
                    app.LoadNoiseinNormalState;
            end  
            guidata(app.Analyzer,app)
        end

        % Menu selected function: FitZwNoisetoElectroThermalModelMenu
        function FitZwNoisetoElectroThermalModel(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.FitZset;
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: ImportCriticalCurrentsMenu
        function ImportCriticalCurrents(app, event)
            fig = app.Analyzer;
            [app.Session{app.TES_ID}.TES.IC, Status]= app.Session{app.TES_ID}.TES.IC.ImportICs(app.Session{app.TES_ID}.Path,fig);
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            if Status
                app.Session{app.TES_ID}.TES.PlotCriticalCurrent(fig);
            else
                app.Session{app.TES_ID}.TES.IC = app.Session{app.TES_ID}.TES.IC.Constructor;
            end
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: ImportFieldScanMenu
        function ImportFieldScan(app, event)
            fig = app.Analyzer;
            [app.Session{app.TES_ID}.TES.FieldScan, Status] = app.Session{app.TES_ID}.TES.FieldScan.ImportScan(app.Session{app.TES_ID}.Path,fig);
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            if Status
                app.Session{app.TES_ID}.TES.PlotFieldScan(fig);
            else
                app.Session{app.TES_ID}.TES.FieldScan.Constructor;
            end
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: SaveTESDataMenu
        function SaveTESData(app, event)
            app.Session{app.TES_ID}.TES.Save([app.Session{app.TES_ID}.Path 'TES_' app.Session{app.TES_ID}.Tag]);
            Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
        end

        % Menu selected function: UnloadTESMenu
        function UnloadTES(app, event)
            ListStr = app.Loaded_TES.Items;
            ListStr(app.TES_ID) = [];
            if isempty(char(ListStr))                
                set(app.Loaded_TES,'Visible','off')
            else              
                set(app.Loaded_TES,'Visible','on','Items',ListStr,'ValueIndex',1,'Visible','on');
            end
            app.Session(app.TES_ID) = [];
            % size(app.Loaded_TES.String,1)
            % app.Loaded_TES.String =
            app.TES_ID = max(size(app.Loaded_TES.Items,1),0);

            % if app.TES_ID == 0 then 'off'
            StrEnable = {'on';'off'};
            StrLabel = {'Unload TES';'Re-Analyze Loaded TES';'Save TES Data';'Plot';'Macro';'Summary';'TES Device';...
                'IV-Curves';'Fit P vs. T';'TES Thermal Parameters vs. %Rn';'TES Thermal Parameter Values';'Get G(T)';...
                'Superconductor State';'Normal State';'Critical Currents';'Field Scan';'Z(w)-Noise Analysis';'Options'};
            for i = 1:length(StrLabel)
                h = findobj(app.Analyzer,'Label',StrLabel{i},'Tag','Analyzer');
                h.Enable = StrEnable{~app.TES_ID+1};
            end

            try
                Enabling(app, app.Session{app.TES_ID},app.TES_ID,app.Analyzer);
            catch
            end
        end

        % Menu selected function: PlotNKGTSetMenu
        function PlotNKGTSet(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            rp = [app.Session{app.TES_ID}.TES.TESThermalP.Rn.Value app.Session{app.TES_ID}.TES.TESThermalN.Rn.Value];
            app.Session{app.TES_ID}.TES.plotNKGTset(fig,rp);
        end

        % Menu selected function: PlotRTsSetMenu
        function PlotRTsSet(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.plotRTs(fig);
        end

        % Menu selected function: PlotRT4pointsMenu
        function PlotRT4points(app, event)
            fig = app.Analyzer;
            indAxes = findobj(fig,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES = app.Session{app.TES_ID}.TES.plotRTs4points(fig,app.Session{app.TES_ID}.ZTDB);
        end

        % Menu selected function: PlotABCTSetMenu
        function PlotABCTSet(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.plotABCT(fig);

            waitfor(helpdlg('After closing this message, select a point for Electrical TES characterization',''));
            figure(fig.hObject);

            % Seleccion mediante teclado de la Rn
            prompt = {'Enter the %Rn (0 < %Rn < 1) for TES Electrical parameters'};
            name = 'Electrical Parameters for % Ibias';
            numlines = 1;
            defaultanswer = {'0.2'};
            answer = inputdlg(prompt,name,numlines,defaultanswer);
            if isempty(answer)
                warndlg('No %Rn value selected',app.version);
                return;
            else
                X = str2double(answer{1});
                if isnan(X)
                    warndlg('Invalid %Rn value',app.version);
                    return;
                end
            end
            app.Session{app.TES_ID}.TES.ABCTrp = X;
            guidata(app.Analyzer,app);
        end

        % Menu selected function: PlotZwvsRnMenu
        function PlotZwvsRn(app, event)
            str = cellstr(num2str(unique([[app.Session{app.TES_ID}.TES.PP.Tbath] ...
                [app.Session{app.TES_ID}.TES.PN.Tbath]])'));
            [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                'SelectionMode','multiple',...
                'ListString',str);
            if isempty(s)
                return;
            end
            Tbath = str2double(str(s))';
            prompt = {'Enter the %Rn range:'};
            name = '%Rn range (0 < %Rn < 1)';
            numlines = [1 70];
            defaultanswer = {'0.5:0.05:0.8'};
            answer = inputdlg(prompt,name,numlines,defaultanswer);
            Rn = eval(['[' answer{1} ']']);
            app.Session{app.TES_ID}.TES.PlotTFTbathRp(Tbath,Rn);
            ButtonName = questdlg('Plot Real Z(w) and Imag Z(w) vs frequency?', ...
                app.VersionStr, ...
                'Yes', 'No', 'No');
            switch ButtonName
                case 'Yes'
                    app.Session{app.TES_ID}.TES.PlotTFReImagTbathRp(Tbath,Rn);
            end
        end

        % Menu selected function: PlotNoisevsRnMenu
        function PlotNoisevsRn(app, event)
            str = cellstr(num2str(unique([[app.Session{app.TES_ID}.TES.PP.Tbath] ...
                [app.Session{app.TES_ID}.TES.PN.Tbath]])'));
            [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                'SelectionMode','multiple',...
                'ListString',str);
            if isempty(s)
                return;
            end
            Tbath = str2double(str(s))';
            prompt = {'Enter the %Rn range:'};
            name = '%Rn range (0 < %Rn < 1)';
            numlines = [1 70];
            defaultanswer = {'0.5:0.05:0.8'};
            answer = inputdlg(prompt,name,numlines,defaultanswer);
            Rn = eval(['[' answer{1} ']']);

            app.Session{app.TES_ID}.TES.PlotNoiseTbathRp(Tbath,Rn);
        end

        % Menu selected function: PlotTESDataMenu
        function PlotTESData(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            str = fieldnames(app.Session{app.TES_ID}.TES.PP(1).p);

            dummy = uimenu('Parent',app.Analyzer,'Visible','off','Tag','Menudummy');
            waitfor(AppGraphicTESData(str,dummy));
            % waitfor(GraphicTESData(str,dummy));
            data = dummy.UserData;
            delete(dummy);

            if ~isempty(data)

                switch data.case
                    case 1 % vs. Rn
                        str = cellstr(num2str(unique([[app.Session{app.TES_ID}.TES.PP.Tbath] ...
                            [app.Session{app.TES_ID}.TES.PN.Tbath]])'));
                        [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                            'SelectionMode','multiple',...
                            'ListString',str);
                        if isempty(s)
                            return;
                        end
                        Tbath = str2double(str(s))';
                        app.Session{app.TES_ID}.TES.PlotTESData(data.param1,[],Tbath,app.Analyzer);
                    case 2 % vs. Tbath
                        prompt = {'Enter the %Rn range:'};
                        name = '%Rn range (0 < %Rn < 1)';
                        numlines = [1 70];
                        defaultanswer = {'0.5:0.05:0.8'};
                        answer = inputdlg(prompt,name,numlines,defaultanswer);
                        Rn = eval(['[' answer{1} ']']);
                        app.Session{app.TES_ID}.TES.PlotTESData(data.param1,Rn,[],app.Analyzer);
                    case 3 % Param1 vs. Param2
                        param = data.param1;
                        param2 = data.param2;
                        params = char([{param};{param2}]);
                        app.Session{app.TES_ID}.TES.PlotTESData(params,[],[],app.Analyzer);
                end
            else
                return;
            end
        end

        % Menu selected function: PlotIVZMenu
        function PlotIVZ(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            str = cellstr(num2str(unique([[app.Session{app.TES_ID}.TES.PP.Tbath] ...
                [app.Session{app.TES_ID}.TES.PN.Tbath]])'));
            [s,~] = listdlg('PromptString','Select Tbath value/s:',...
                'SelectionMode','multiple',...
                'ListString',str);
            if isempty(s)
                return;
            end
            Tbath = str2double(str(s))';
            fig = app.Session{app.TES_ID}.TES.CompareIV_Z(app.Session{app.TES_ID}.TES.IVsetP,app.Session{app.TES_ID}.TES.PP,Tbath);
            app.Session{app.TES_ID}.TES.CompareIV_Z(app.Session{app.TES_ID}.TES.IVsetN,app.Session{app.TES_ID}.TES.PN,Tbath);
        end

        % Menu selected function: PlotCriticalCurrentsMenu
        function PlotCriticalCurrents(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.PlotCriticalCurrent(fig.hObject);
        end

        % Menu selected function: PlotFieldScanMenu
        function PlotFieldScan(app, event)
            fig.hObject = app.Analyzer;
            indAxes = findobj(fig.hObject,'Type','Axes');
            delete(indAxes);
            app.Session{app.TES_ID}.TES.PlotFieldScan(fig.hObject);
        end

        % Button down function: Analyzer
        function AnalyzerButtonDown(app, event)
            % Callback del componente (ej: UIAxesButtonDown, PanelButtonDown...)

            % Verificar si el usuario hizo clic derecho
            switch event.Source.SelectionType
                case 'alt'
                    % app.Analyzer.ContextMenu = [];
                    % 
                    % app.Analyzer.ContextMenu = app.Save_menu;
                    % % ha = findobj(event.Source,'Type','Axes');
                    % if isempty(ha)
                    %     set(event.Source,'uicontextmenu',[]);
                    % else
                    %     cmenu = uicontextmenu('Visible','on','Parent',app.Analyzer);
                    %     c6 = uimenu(cmenu,'Label','Save Graph','Callback',{@SaveGraph},'UserData',event.Source);
                    %     set(event.Source,'uicontextmenu',cmenu,'Visible','on');
                    %     % set(event.Source,'uicontextmenu',cmenu);
                    %     open(cmenu,cmenu.Position);
                    % 
                    % end
                % app.Analyzer.ContextMenu = app.NKGT_menu;
                % 
                case 'extend'
                    % x = app.Analyzer.CurrentPoint(1);
                    % y = app.Analyzer.CurrentPoint(2);
                    
                    % open(app.NKGT_menu,x,y)
                case 'normal'
            
                otherwise
                % app.Analyzer.ContextMenu = app.NKGT_menu;
                % pause(0.001)
            end                         
      
        end

        % Close request function: Analyzer
        function AnalyzerCloseRequest(app, event)
            ButtonName = questdlg('Are you sure to close Analyzer?', ...
                app.VersionStr, ...
                'Yes', 'No', 'Yes');
            switch ButtonName
                case 'No'
                    return;
            end

            if app.TES_ID ~= 0
                ButtonName = questdlg('Do you want to save before exit?', ...
                    app.VersionStr, ...
                    'Yes', 'No', 'Yes');
                switch ButtonName
                    case 'Yes'
                        app.Session{app.TES_ID}.TES.Save([app.Session{app.TES_ID}.Path 'TES_' app.Session{app.TES_ID}.Tag]);
                    case 'No'
                end
            end
            % rmvpath([pwd filesep 'AnalysisFcn']);
            delete(app);
            
            
        end

        % Menu selected function: LoadPulseSet
        function LoadPulseSetMenuSelected(app, event)
            namedir = pwd;
            [namefile,namedir] = uigetfile('*.fits','Select Pulse FIT file',namedir);
            filename = [namedir namefile];
            addpath('.\HandleLibrary\');
            addpath('.\jsonlab-2.0\');

            d = uiprogressdlg(app.Analyzer, 'Title', 'Extracting Pull Set Info... Please wait', ...
                'Message', 'Task could take some time...', ...
                'Indeterminate', 'on');
            figure(app.Analyzer);
            app.PulseSet = app.PulseSet.Construction;
            app.PulseSet = app.PulseSet.CharacterizeRawPull(filename);
            close(d)
            app.PulsesSetName.Items = {filename};
            app.PulsesSetName.Visible = 'on';
            app.LoadedTESLabel_2.Visible = 'on';
            Enabling_TESResMenus(app);
            waitfor(msgbox('Pulse Set File is now loaded','Analyzer Info...'));
            guidata(app.Analyzer,app);
        end

        % Menu selected function: IdenKaNoise
        function IdenKaNoiseMenuSelected(app, event)
                       
            d = uiprogressdlg(app.Analyzer, 'Title', 'Identifying Pulses and Noises... Please wait', ...
                'Message', 'Task could take some time...', ...
                'Indeterminate', 'on');
            figure(app.Analyzer);
            app.PulseSet = app.PulseSet.DetIndexes;
            close(d);
            Enabling_TESResMenus(app);

            answer = questdlg('Show Ka Data?','Analyzer Info...','Yes','No','No');
            switch answer
                case 'Yes'
                    app.PulseSet.plotKapulses;
            end
            answer = questdlg('Show Noise Data?','Analyzer Info...','Yes','No','No');
            switch answer
                case 'Yes'
                    app.PulseSet.plotNoisepulses;
            end
            guidata(app.Analyzer,app);
        end

        % Menu selected function: RemoveOutliers
        function RemoveOutliersMenuSelected(app, event)
            

            prompt = {'Enter IQR factor for removing data'};
            name = 'Remove outliers from median values';
            numlines = 1;
            defaultanswer = {'3'};
            answer = inputdlg(prompt,name,numlines,defaultanswer);
            if isempty(answer)
                warndlg('No value selected',app.version);
                return;
            else
                X = str2double(answer{1});
                if isnan(X)
                    warndlg('Invalid IQR factor value',app.version);
                    return;
                end
            end

            %% Revisar si hay clusters
            if isempty(app.PulseSet.clustNum)
                app.PulseSet = app.PulseSet.RemoveOutliers_DC(X);
            else
                str = cellstr(num2str((1:app.PulseSet.clustNum)'));
                [s,~] = listdlg('PromptString','Select Cluster Set:',...
                    'SelectionMode','single',...
                    'ListString',str);
                if isempty(s)                           
                    app.PulseSet = app.PulseSet.RemoveOutliers_DC(X);                    
                else
                    CN = str2double(str(s));
                    app.PulseSet = app.PulseSet.RemoveOutliers_DC(X,CN);
                end               

            end
            Enabling_TESResMenus(app);
            guidata(app.Analyzer,app);
        end

        % Menu selected function: ClusteringDatabyDClevel
        function ClusteringDatabyDClevelMenuSelected(app, event)
            d = uiprogressdlg(app.Analyzer, 'Title', 'Identifying cluster data... Please wait', ...
                'Message', 'Task could take some time...', ...
                'Indeterminate', 'on');
            figure(app.Analyzer);
            app.PulseSet = app.PulseSet.ClusteringData;
            d.Message = ['Identified Number of clusters: ' num2str(app.PulseSet.clustNum)];
            pause(2);
            close(d);
            Enabling_TESResMenus(app);
            guidata(app.Analyzer,app);
        end

        % Menu selected function: TESResolutionestimation
        function TESResolutionestimationMenuSelected(app, event)


            answer = questdlg('Would you like to remove outliers?', ...
            	app.VersionStr, ...
            	'Yes','No','No');
            % Handle response
            switch answer
                case 'Yes'
                    prompt = {'Enter IQR factor for removing data'};
                    name = 'Remove outliers from median values';
                    numlines = 1;
                    defaultanswer = {'3'};
                    answer = inputdlg(prompt,name,numlines,defaultanswer);
                    if isempty(answer)
                        warndlg('No value selected',app.version);
                        return;
                    else
                        X = str2double(answer{1});
                        if isnan(X)
                            warndlg('Invalid IQR factor value',app.version);
                            return;
                        end
                    end
                otherwise
                    X = 0;
            end
            
            answer = questdlg('Would you set number of bins manually or automatic?', ...
            	app.VersionStr, ...
            	'Automatic','Based on Data','Manually','Automatic');
            % Handle response
            switch answer
                case 'Automatic'
                    NbinSelect = 0;

                case 'Based on Data'
                    NbinSelect = 1;

                case 'Manually'
                    NbinSelect = 2;

            end

            if isempty(app.PulseSet.clustNum)
                [eVres, Nbin] = app.PulseSet.ResolutionEstimation(0,X);
                
            else
                str = cellstr(num2str((1:app.PulseSet.clustNum)'));
                [s,~] = listdlg('PromptString','Select Cluster Set:',...
                    'SelectionMode','single',...
                    'ListString',str);
                if isempty(s)                    
                    d = uiprogressdlg(app.Analyzer, 'Title', 'Estimating TES Resolution... Please wait', ...
                        'Message', 'Task could take some time...', ...
                        'Indeterminate', 'on');
                    figure(app.Analyzer);
                    app.PulseSet = app.PulseSet.ResolutionEstimation(0,X,NbinSelect);  


                else
                    CN = str2double(str(s));
                    d = uiprogressdlg(app.Analyzer, 'Title', 'Estimating TES Resolution... Please wait', ...
                        'Message', 'Task could take some time...', ...
                        'Indeterminate', 'on');
                    figure(app.Analyzer);
                    app.PulseSet = app.PulseSet.ResolutionEstimation(CN,X,NbinSelect);
                    
                end        
                close(d)
            end
            Enabling_TESResMenus(app);
            guidata(app.Analyzer,app);
        end

        % Menu selected function: BuildOptimalFilter
        function BuildOptimalFilterMenuSelected(app, event)
            d = uiprogressdlg(app.Analyzer, 'Title', 'Build Optimal filter... Please wait', ...
                'Message', 'Task could take some time...', ...
                'Indeterminate', 'on');
            figure(app.Analyzer);
            app.OF = app.OF.buildMeanPSDfromFITS(app.PulseSet.filename,app.PulseSet.indNoise);
            app.OF = app.OF.buildMeanPulsefromFITS(app.PulseSet.filename,app.PulseSet.indKaGood);
            app.OF = app.OF.buildOptimalFilter;

            app.PulseSet = app.PulseSet.OFT(app.OF.OF);
            close(d);
            Enabling_TESResMenus(app);
            guidata(app.Analyzer,app);            
        end

        % Menu selected function: PlotKa
        function PlotKaMenuSelected(app, event)
            app.PulseSet.plotKapulses;
        end

        % Menu selected function: PlotNoise
        function PlotNoiseMenuSelected(app, event)
            app.PulseSet.plotNoisepulses;
        end

        % Menu selected function: PlotOF
        function PlotOFMenuSelected(app, event)
            app.OF.plotOFfilter;
        end

        % Menu selected function: Plotclust
        function PlotclustMenuSelected(app, event)
            str = {'dc';'dc_std';'area';'optArea';'amp';'tmax';'Amax';'OFT_Energy'};
            [s,~] = listdlg('PromptString','Select one parameters:',...
                'SelectionMode','single','ListString',str);
            if isempty(s)
                return;
            end
            featlabel = str{s};  
            figure(app.Analyzer);
            if ~isempty(app.PulseSet.clustNum)
                figure,hold on;
                for i = 1:app.PulseSet.clustNum
                    indices_validos = find(app.PulseSet.etiquetas_finales == i);
                    plot(indices_validos,app.PulseSet.(featlabel)(indices_validos),'.')
                end
                if contains(featlabel,'OFT_Energy')
                    ylabel('OFT_{Energy}');
                else
                    ylabel(featlabel);
                end
                xlabel('Event (#)')
                legend;
            else
                warndlg('Data no clustered.','Analyzer Info...');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analyzer = uifigure('Visible', 'off');
            app.Analyzer.Name = 'Dark TES Analyzer';
            app.Analyzer.CloseRequestFcn = createCallbackFcn(app, @AnalyzerCloseRequest, true);
            app.Analyzer.WindowButtonDownFcn = createCallbackFcn(app, @AnalyzerWindowButtonDown, true);
            app.Analyzer.ButtonDownFcn = createCallbackFcn(app, @AnalyzerButtonDown, true);
            app.Analyzer.Tag = 'Analyzer';
            app.TESDataMenu = uimenu(app.Analyzer);
            app.TESDataMenu.MenuSelectedFcn = createCallbackFcn(app, @TESData, true);
            app.TESDataMenu.Text = 'TES Data';
            app.TESDataMenu.Tag = 'Analyzer';
            app.LoadTESMenu = uimenu(app.TESDataMenu);
            app.LoadTESMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTES, true);
            app.LoadTESMenu.Text = 'Load TES';
            app.LoadTESMenu.Tag = 'Analyzer';
            app.TESAnalysisMenu = uimenu(app.TESDataMenu);
            app.TESAnalysisMenu.Text = 'TES Analysis';
            app.TESAnalysisMenu.Tag = 'Analyzer';
            app.SetDataPathMenu = uimenu(app.TESAnalysisMenu);
            app.SetDataPathMenu.MenuSelectedFcn = createCallbackFcn(app, @SetDataPath, true);
            app.SetDataPathMenu.Text = 'Set Data Path';
            app.SetDataPathMenu.Tag = 'Analyzer';
            app.TESDeviceMenu = uimenu(app.TESAnalysisMenu);
            app.TESDeviceMenu.Text = 'TES Device';
            app.TESDeviceMenu.Tag = 'Analyzer';
            app.TESDimensionsMenu = uimenu(app.TESDeviceMenu);
            app.TESDimensionsMenu.MenuSelectedFcn = createCallbackFcn(app, @TES_Dimensions, true);
            app.TESDimensionsMenu.Text = 'TES Dimensions';
            app.TESDimensionsMenu.Tag = 'Analyzer';
            app.SQUIDTemperatureMenu = uimenu(app.TESDeviceMenu);
            app.SQUIDTemperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @SQUID_Temperature, true);
            app.SQUIDTemperatureMenu.Text = 'SQUID Temperature';
            app.SQUIDTemperatureMenu.Tag = 'Analyzer';
            app.CircuitValuesMenu = uimenu(app.TESDeviceMenu);
            app.CircuitValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @Circuit_Values, true);
            app.CircuitValuesMenu.Text = 'Circuit Values';
            app.CircuitValuesMenu.Tag = 'Analyzer';
            app.CircuitNoiseMenu = uimenu(app.TESDeviceMenu);
            app.CircuitNoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @Circuit_Noise, true);
            app.CircuitNoiseMenu.Text = 'Circuit Noise';
            app.CircuitNoiseMenu.Tag = 'Analyzer';
            app.IVCurvesMenu = uimenu(app.TESAnalysisMenu);
            app.IVCurvesMenu.Text = 'IV-Curves';
            app.IVCurvesMenu.Tag = 'Analyzer';
            app.ImportIVCurvesMenu = uimenu(app.IVCurvesMenu);
            app.ImportIVCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportIVCurves, true);
            app.ImportIVCurvesMenu.Text = 'Import IV-Curves';
            app.ImportIVCurvesMenu.Tag = 'Analyzer';
            app.CenterIVCurvesMenu = uimenu(app.IVCurvesMenu);
            app.CenterIVCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @CenterIVCurves, true);
            app.CenterIVCurvesMenu.Text = 'Center IV-Curves';
            app.CenterIVCurvesMenu.Tag = 'Analyzer';
            app.CheckIVCurvesMenu = uimenu(app.IVCurvesMenu);
            app.CheckIVCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckIVCurves, true);
            app.CheckIVCurvesMenu.Text = 'Check IV-Curves';
            app.CheckIVCurvesMenu.Tag = 'Analyzer';
            app.SaveIVCurveSetMenu = uimenu(app.IVCurvesMenu);
            app.SaveIVCurveSetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIVCurveSet, true);
            app.SaveIVCurveSetMenu.Text = 'Save IV-Curve Set';
            app.SaveIVCurveSetMenu.Tag = 'Analyzer';
            app.FitPvsTMenu = uimenu(app.IVCurvesMenu);
            app.FitPvsTMenu.MenuSelectedFcn = createCallbackFcn(app, @FitPvsT, true);
            app.FitPvsTMenu.Text = 'Fit P vs. T';
            app.FitPvsTMenu.Tag = 'Analyzer';
            app.TESThermalParametersvsRnMenu = uimenu(app.IVCurvesMenu);
            app.TESThermalParametersvsRnMenu.MenuSelectedFcn = createCallbackFcn(app, @TESThermalParametersvsRn, true);
            app.TESThermalParametersvsRnMenu.Text = 'TES Thermal Parameters vs. %Rn';
            app.TESThermalParametersvsRnMenu.Tag = 'Analyzer';
            app.TESThermalParameterValuesMenu = uimenu(app.IVCurvesMenu);
            app.TESThermalParameterValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @TESThermalParameterValues, true);
            app.TESThermalParameterValuesMenu.Text = 'TES Thermal Parameter Values';
            app.TESThermalParameterValuesMenu.Tag = 'Analyzer';
            app.GetGTMenu = uimenu(app.IVCurvesMenu);
            app.GetGTMenu.MenuSelectedFcn = createCallbackFcn(app, @GetGT, true);
            app.GetGTMenu.Text = 'Get G(T)';
            app.GetGTMenu.Tag = 'Analyzer';
            app.SuperconductorStateMenu = uimenu(app.TESAnalysisMenu);
            app.SuperconductorStateMenu.Text = 'Superconductor State';
            app.SuperconductorStateMenu.Tag = 'Analyzer';
            app.LoadTFinSuperconductorStateTFSMenu = uimenu(app.SuperconductorStateMenu);
            app.LoadTFinSuperconductorStateTFSMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTFinSuperconductorStateTFS, true);
            app.LoadTFinSuperconductorStateTFSMenu.Text = 'Load TF in Superconductor State (TFS)';
            app.LoadTFinSuperconductorStateTFSMenu.Tag = 'Analyzer';
            app.CheckTFSMenu = uimenu(app.SuperconductorStateMenu);
            app.CheckTFSMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckTFS, true);
            app.CheckTFSMenu.Text = 'Check TFS';
            app.CheckTFSMenu.Tag = 'Analyzer';
            app.LoadNoiseinSuperconductorStateMenu = uimenu(app.SuperconductorStateMenu);
            app.LoadNoiseinSuperconductorStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNoiseinSuperconductorState, true);
            app.LoadNoiseinSuperconductorStateMenu.Text = 'Load Noise in Superconductor State';
            app.LoadNoiseinSuperconductorStateMenu.Tag = 'Analyzer';
            app.CheckSuperconductorStateNoiseMenu = uimenu(app.SuperconductorStateMenu);
            app.CheckSuperconductorStateNoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckSuperconductorStateNoise, true);
            app.CheckSuperconductorStateNoiseMenu.Text = 'Check Superconductor State Noise';
            app.CheckSuperconductorStateNoiseMenu.Tag = 'Analyzer';
            app.NormalStateMenu = uimenu(app.TESAnalysisMenu);
            app.NormalStateMenu.Text = 'Normal State';
            app.NormalStateMenu.Tag = 'Analyzer';
            app.LoadTFinNormalStateTFNMenu = uimenu(app.NormalStateMenu);
            app.LoadTFinNormalStateTFNMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadTFinNormalStateTFN, true);
            app.LoadTFinNormalStateTFNMenu.Text = 'Load TF in Normal State (TFN)';
            app.LoadTFinNormalStateTFNMenu.Tag = 'Analyzer';
            app.CheckTFNMenu = uimenu(app.NormalStateMenu);
            app.CheckTFNMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckTFN, true);
            app.CheckTFNMenu.Text = 'Check TFN';
            app.CheckTFNMenu.Tag = 'Analyzer';
            app.LoadNoiseinNormalStateMenu = uimenu(app.NormalStateMenu);
            app.LoadNoiseinNormalStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNoiseinNormalState, true);
            app.LoadNoiseinNormalStateMenu.Text = 'Load Noise in Normal State';
            app.LoadNoiseinNormalStateMenu.Tag = 'Analyzer';
            app.CheckNormalStateNoiseMenu = uimenu(app.NormalStateMenu);
            app.CheckNormalStateNoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckNormalStateNoise, true);
            app.CheckNormalStateNoiseMenu.Text = 'Check Normal State Noise';
            app.CheckNormalStateNoiseMenu.Tag = 'Analyzer';
            app.ZwNoiseAnalysisMenu = uimenu(app.TESAnalysisMenu);
            app.ZwNoiseAnalysisMenu.Text = 'Z(w)-Noise Analysis';
            app.ZwNoiseAnalysisMenu.Tag = 'Analyzer';
            app.ZwDerivedLMenu = uimenu(app.ZwNoiseAnalysisMenu);
            app.ZwDerivedLMenu.MenuSelectedFcn = createCallbackFcn(app, @ZwDerivedL, true);
            app.ZwDerivedLMenu.Text = 'Z(w) Derived L';
            app.ZwDerivedLMenu.Tag = 'Analyzer';
            app.FitZwNoisetoElectroThermalModelMenu = uimenu(app.ZwNoiseAnalysisMenu);
            app.FitZwNoisetoElectroThermalModelMenu.MenuSelectedFcn = createCallbackFcn(app, @FitZwNoisetoElectroThermalModel, true);
            app.FitZwNoisetoElectroThermalModelMenu.Text = 'Fit Z(w)-Noise to ElectroThermal Model';
            app.FitZwNoisetoElectroThermalModelMenu.Tag = 'Analyzer';
            app.CriticalCurrentsMenu = uimenu(app.TESAnalysisMenu);
            app.CriticalCurrentsMenu.Text = 'Critical Currents';
            app.CriticalCurrentsMenu.Tag = 'Analyzer';
            app.ImportCriticalCurrentsMenu = uimenu(app.CriticalCurrentsMenu);
            app.ImportCriticalCurrentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportCriticalCurrents, true);
            app.ImportCriticalCurrentsMenu.Text = 'Import Critical Currents';
            app.ImportCriticalCurrentsMenu.Tag = 'Analyzer';
            app.FieldScanMenu = uimenu(app.TESAnalysisMenu);
            app.FieldScanMenu.Text = 'Field Scan';
            app.FieldScanMenu.Tag = 'Analyzer';
            app.ImportFieldScanMenu = uimenu(app.FieldScanMenu);
            app.ImportFieldScanMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportFieldScan, true);
            app.ImportFieldScanMenu.Text = 'Import Field Scan';
            app.ImportFieldScanMenu.Tag = 'Analyzer';
            app.UnloadTESMenu = uimenu(app.TESDataMenu);
            app.UnloadTESMenu.MenuSelectedFcn = createCallbackFcn(app, @UnloadTES, true);
            app.UnloadTESMenu.Enable = 'off';
            app.UnloadTESMenu.Text = 'Unload TES';
            app.UnloadTESMenu.Tag = 'Analyzer';
            app.ReAnalyzeLoadedTESMenu = uimenu(app.TESDataMenu);
            app.ReAnalyzeLoadedTESMenu.Enable = 'off';
            app.ReAnalyzeLoadedTESMenu.Text = 'Re-Analyze Loaded TES';
            app.ReAnalyzeLoadedTESMenu.Tag = 'Analyzer';
            app.SaveTESDataMenu = uimenu(app.TESDataMenu);
            app.SaveTESDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveTESData, true);
            app.SaveTESDataMenu.Enable = 'off';
            app.SaveTESDataMenu.Text = 'Save TES Data';
            app.SaveTESDataMenu.Tag = 'Analyzer';
            app.TESResolutionMenu = uimenu(app.TESDataMenu);
            app.TESResolutionMenu.Text = 'TES Resolution';
            app.LoadPulseSet = uimenu(app.TESResolutionMenu);
            app.LoadPulseSet.MenuSelectedFcn = createCallbackFcn(app, @LoadPulseSetMenuSelected, true);
            app.LoadPulseSet.Text = 'Load Pulse Set';
            app.LoadPulseSet.Tag = 'Analyzer';
            app.IdenKaNoise = uimenu(app.TESResolutionMenu);
            app.IdenKaNoise.MenuSelectedFcn = createCallbackFcn(app, @IdenKaNoiseMenuSelected, true);
            app.IdenKaNoise.Enable = 'off';
            app.IdenKaNoise.Text = 'Identify Ka and Noise data';
            app.IdenKaNoise.Tag = 'Analyzer';
            app.PlotPulses = uimenu(app.TESResolutionMenu);
            app.PlotPulses.Enable = 'off';
            app.PlotPulses.Text = 'Plot Options';
            app.PlotPulses.Tag = 'Analyzer';
            app.PlotKa = uimenu(app.PlotPulses);
            app.PlotKa.MenuSelectedFcn = createCallbackFcn(app, @PlotKaMenuSelected, true);
            app.PlotKa.Enable = 'off';
            app.PlotKa.Text = 'Plot Ka labeled pulses';
            app.PlotKa.Tag = 'Analyzer';
            app.PlotNoise = uimenu(app.PlotPulses);
            app.PlotNoise.MenuSelectedFcn = createCallbackFcn(app, @PlotNoiseMenuSelected, true);
            app.PlotNoise.Enable = 'off';
            app.PlotNoise.Text = 'Plot Noise labeled pulses';
            app.PlotNoise.Tag = 'Analyzer';
            app.PlotOF = uimenu(app.PlotPulses);
            app.PlotOF.MenuSelectedFcn = createCallbackFcn(app, @PlotOFMenuSelected, true);
            app.PlotOF.Enable = 'off';
            app.PlotOF.Text = 'Plot Optimal Filter';
            app.PlotOF.Tag = 'Analyzer';
            app.Plotclust = uimenu(app.PlotPulses);
            app.Plotclust.MenuSelectedFcn = createCallbackFcn(app, @PlotclustMenuSelected, true);
            app.Plotclust.Enable = 'off';
            app.Plotclust.Text = 'Plot Clustering data';
            app.Plotclust.Tag = 'Analyzer';
            app.BuildOptimalFilter = uimenu(app.TESResolutionMenu);
            app.BuildOptimalFilter.MenuSelectedFcn = createCallbackFcn(app, @BuildOptimalFilterMenuSelected, true);
            app.BuildOptimalFilter.Enable = 'off';
            app.BuildOptimalFilter.Text = 'Build Optimal Filter';
            app.BuildOptimalFilter.Tag = 'Analyzer';
            app.RemoveOutliers = uimenu(app.TESResolutionMenu);
            app.RemoveOutliers.MenuSelectedFcn = createCallbackFcn(app, @RemoveOutliersMenuSelected, true);
            app.RemoveOutliers.Enable = 'off';
            app.RemoveOutliers.Text = 'Remove Outliers';
            app.RemoveOutliers.Tag = 'Analyzer';
            app.ClusteringDatabyDClevel = uimenu(app.TESResolutionMenu);
            app.ClusteringDatabyDClevel.MenuSelectedFcn = createCallbackFcn(app, @ClusteringDatabyDClevelMenuSelected, true);
            app.ClusteringDatabyDClevel.Enable = 'off';
            app.ClusteringDatabyDClevel.Text = 'Clustering Data by DC level';
            app.ClusteringDatabyDClevel.Tag = 'Analyzer';
            app.TESResolutionestimation = uimenu(app.TESResolutionMenu);
            app.TESResolutionestimation.MenuSelectedFcn = createCallbackFcn(app, @TESResolutionestimationMenuSelected, true);
            app.TESResolutionestimation.Enable = 'off';
            app.TESResolutionestimation.Text = 'TES Resolution estimation';
            app.TESResolutionestimation.Tag = 'Analyzer';
            app.PlotMenu = uimenu(app.Analyzer);
            app.PlotMenu.Enable = 'off';
            app.PlotMenu.Text = 'Plot';
            app.PlotMenu.Tag = 'Analyzer';
            app.PlotNKGTSetMenu = uimenu(app.PlotMenu);
            app.PlotNKGTSetMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotNKGTSet, true);
            app.PlotNKGTSetMenu.Text = 'Plot NKGT Set';
            app.PlotNKGTSetMenu.Tag = 'Analyzer';
            app.PlotRTsSetMenu = uimenu(app.PlotMenu);
            app.PlotRTsSetMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotRTsSet, true);
            app.PlotRTsSetMenu.Text = 'Plot RTs Set';
            app.PlotRTsSetMenu.Tag = 'Analyzer';
            app.PlotRT4pointsMenu = uimenu(app.PlotMenu);
            app.PlotRT4pointsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotRT4points, true);
            app.PlotRT4pointsMenu.Text = 'Plot RT 4 points';
            app.PlotRT4pointsMenu.Tag = 'Analyzer';
            app.PlotABCTSetMenu = uimenu(app.PlotMenu);
            app.PlotABCTSetMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotABCTSet, true);
            app.PlotABCTSetMenu.Text = 'Plot ABCT Set';
            app.PlotABCTSetMenu.Tag = 'Analyzer';
            app.PlotZwvsRnMenu = uimenu(app.PlotMenu);
            app.PlotZwvsRnMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotZwvsRn, true);
            app.PlotZwvsRnMenu.Text = 'Plot Z(w) vs %Rn';
            app.PlotZwvsRnMenu.Tag = 'Analyzer';
            app.PlotNoisevsRnMenu = uimenu(app.PlotMenu);
            app.PlotNoisevsRnMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotNoisevsRn, true);
            app.PlotNoisevsRnMenu.Text = 'Plot Noise vs %Rn';
            app.PlotNoisevsRnMenu.Tag = 'Analyzer';
            app.PlotTESDataMenu = uimenu(app.PlotMenu);
            app.PlotTESDataMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotTESData, true);
            app.PlotTESDataMenu.Text = 'Plot TES Data';
            app.PlotTESDataMenu.Tag = 'Analyzer';
            app.PlotIVZMenu = uimenu(app.PlotMenu);
            app.PlotIVZMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotIVZ, true);
            app.PlotIVZMenu.Text = 'Plot IV-Z';
            app.PlotIVZMenu.Tag = 'Analyzer';
            app.PlotCriticalCurrentsMenu = uimenu(app.PlotMenu);
            app.PlotCriticalCurrentsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotCriticalCurrents, true);
            app.PlotCriticalCurrentsMenu.Text = 'Plot Critical Currents';
            app.PlotCriticalCurrentsMenu.Tag = 'Analyzer';
            app.PlotFieldScanMenu = uimenu(app.PlotMenu);
            app.PlotFieldScanMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotFieldScan, true);
            app.PlotFieldScanMenu.Text = 'Plot Field Scan';
            app.PlotFieldScanMenu.Tag = 'Analyzer';
            app.MacroMenu = uimenu(app.Analyzer);
            app.MacroMenu.Enable = 'off';
            app.MacroMenu.Text = 'Macro';
            app.MacroMenu.Tag = 'Analyzer';
            app.PlotNKGTSetsMenu = uimenu(app.MacroMenu);
            app.PlotNKGTSetsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotNKGTSetsMenu.Text = 'Plot NKGT Sets';
            app.PlotNKGTSetsMenu.Tag = 'Analyzer';
            app.PlotRTsSetsMenu = uimenu(app.MacroMenu);
            app.PlotRTsSetsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotRTsSetsMenu.Text = 'Plot RTs Sets';
            app.PlotRTsSetsMenu.Tag = 'Analyzer';
            app.PlotRTs4pointsMenu = uimenu(app.MacroMenu);
            app.PlotRTs4pointsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotRTs4pointsMenu.Text = 'Plot RTs 4 points';
            app.PlotRTs4pointsMenu.Tag = 'Analyzer';
            app.PlotABCTSetsMenu = uimenu(app.MacroMenu);
            app.PlotABCTSetsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotABCTSetsMenu.Text = 'Plot ABCT Sets';
            app.PlotABCTSetsMenu.Tag = 'Analyzer';
            app.PlotTESsDataMenu = uimenu(app.MacroMenu);
            app.PlotTESsDataMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotTESsDataMenu.Text = 'Plot TESs Data';
            app.PlotTESsDataMenu.Tag = 'Analyzer';
            app.PlotIVZsMenu = uimenu(app.MacroMenu);
            app.PlotIVZsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotIVZsMenu.Text = 'Plot IV-Zs';
            app.PlotIVZsMenu.Tag = 'Analyzer';
            app.PlotsCriticalCurrentsMenu = uimenu(app.MacroMenu);
            app.PlotsCriticalCurrentsMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotsCriticalCurrentsMenu.Text = 'Plots Critical Currents';
            app.PlotsCriticalCurrentsMenu.Tag = 'Analyzer';
            app.PlotsFieldScanMenu = uimenu(app.MacroMenu);
            app.PlotsFieldScanMenu.MenuSelectedFcn = createCallbackFcn(app, @MacroTES, true);
            app.PlotsFieldScanMenu.Text = 'Plots Field Scan';
            app.PlotsFieldScanMenu.Tag = 'Analyzer';
            app.SummaryMenu = uimenu(app.Analyzer);
            app.SummaryMenu.Enable = 'off';
            app.SummaryMenu.Text = 'Summary';
            app.SummaryMenu.Tag = 'Analyzer';
            app.ZwNoiseViewerMenu = uimenu(app.SummaryMenu);
            app.ZwNoiseViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @SummaryTES, true);
            app.ZwNoiseViewerMenu.Text = 'Z(w)-Noise Viewer';
            app.ZwNoiseViewerMenu.Tag = 'Analyzer';
            app.WordGraphicalReportMenu = uimenu(app.SummaryMenu);
            app.WordGraphicalReportMenu.MenuSelectedFcn = createCallbackFcn(app, @SummaryTES, true);
            app.WordGraphicalReportMenu.Text = 'Word Graphical Report';
            app.WordGraphicalReportMenu.Tag = 'Analyzer';
            app.OptionsMenu = uimenu(app.Analyzer);
            app.OptionsMenu.Enable = 'off';
            app.OptionsMenu.Text = 'Options';
            app.OptionsMenu.Tag = 'Analyzer';
            app.PvsTModelFittingOptionsMenu = uimenu(app.OptionsMenu);
            app.PvsTModelFittingOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsTES, true);
            app.PvsTModelFittingOptionsMenu.Text = 'P vs T Model Fitting Options';
            app.PvsTModelFittingOptionsMenu.Tag = 'Analyzer';
            app.ElectroThermalModelFittingOptionsMenu = uimenu(app.OptionsMenu);
            app.ElectroThermalModelFittingOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsTES, true);
            app.ElectroThermalModelFittingOptionsMenu.Text = 'Electro Thermal Model Fitting Options';
            app.ElectroThermalModelFittingOptionsMenu.Tag = 'Analyzer';
            app.ReportOptionsMenu = uimenu(app.OptionsMenu);
            app.ReportOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsTES, true);
            app.ReportOptionsMenu.Text = 'Report Options';
            app.ReportOptionsMenu.Tag = 'Analyzer';
            app.Help = uimenu(app.Analyzer);
            app.Help.Text = 'Help';
            app.Help.Tag = 'Analyzer';
            app.Guide = uimenu(app.Help);
            app.Guide.MenuSelectedFcn = createCallbackFcn(app, @HelpTES, true);
            app.Guide.Text = 'Guide';
            app.Guide.Tag = 'Analyzer';
            app.About = uimenu(app.Help);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @HelpTES, true);
            app.About.Text = 'About';
            app.About.Tag = 'Analyzer';
            app.LoadedTESLabel = uilabel(app.Analyzer);
            app.LoadedTESLabel.Visible = 'off';
            app.LoadedTESLabel.Text = 'Loaded TES';
            app.Loaded_TES = uidropdown(app.Analyzer);
            app.Loaded_TES.Items = {''};
            app.Loaded_TES.ValueChangedFcn = createCallbackFcn(app, @Loaded_TES_Callback, true);
            app.Loaded_TES.Visible = 'off';
            app.Loaded_TES.Value = '';
            app.LoadedTESLabel_2 = uilabel(app.Analyzer);
            app.LoadedTESLabel_2.Visible = 'off';
            app.LoadedTESLabel_2.Text = 'Pulses Set';
            app.PulsesSetName = uidropdown(app.Analyzer);
            app.PulsesSetName.Items = {''};
            app.PulsesSetName.Visible = 'off';
            app.PulsesSetName.Value = '';
            % Show the figure after all components are created
            app.Analyzer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Analyzer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analyzer)
        end
    end
end