classdef AppConf_Setup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        Cancel   matlab.ui.control.Button
        Save     matlab.ui.control.Button
        Remove   matlab.ui.control.Button
        Add      matlab.ui.control.Button
        Options  matlab.ui.control.DropDown
        Panel    matlab.ui.container.Panel
        Table    matlab.ui.control.Table
    end

    
    properties (Access = private)
        varargin % Description
        VersionStr
    end
    
    methods (Access = private)

     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.varargin = varargin;
            app.figure1.Units = 'normalized';
            position = get(app.figure1,'Position');
            set(app.figure1,'Color',[0 120 180]/255,'Position',...
                [0.5-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');

            try
                app.VersionStr = app.varargin{3}.version; %'ZarTES v4.1';
            catch
                app.VersionStr = '';
            end
            % set(app.figure1,'Name','TF_Viewer');
            

            switch varargin{1}.Tag
                case 'Squid_Pulse_Input_Conf'
                    hndl = guidata(varargin{1});
                    handles.figure1.Name = 'Pulse Input Configuration';
                    handles.Table.ColumnEditable = [false true false];
                    handles.Table.ColumnName = {'Parameter';'Value';'Units'};
                    handles.Options.Visible = 'off';
                    handles.Table.Data = [{'Amp'} {hndl.Squid.PulseAmp.Value} {hndl.Squid.PulseAmp.Units};...
                        {'Range'} {hndl.Squid.PulseDT.Value} {hndl.Squid.PulseDT.Units};...
                        {'Duration'} {hndl.Squid.PulseDuration.Value} {hndl.Squid.PulseDuration.Units};...
                        {'RL'} {hndl.Squid.RL.Value} {hndl.Squid.RL.Units}];

                case 'DSA_TF_Zw_Conf'
                    handles.figure1.Name = 'DSA Configuration';
                    hndl = guidata(varargin{1});
                    if isfield(hndl.SetupTES,'SetupTES')
                        DSA_Conf = hndl.SetupTES.DSA.Config;
                        handles1.SetupTES = hndl.SetupTES;
                    else
                        DSA_Conf = hndl.DSA.Config;
                        handles1.SetupTES = hndl;
                    end
                    ConfInstrs{1} = DSA_Conf.SSine;
                    ConfInstrs{2} = DSA_Conf.FSine;
                    ConfInstrs{3} = DSA_Conf.WNoise;
                    handles.Options.String = {'Sweept Sine';'Fixed Sine';'White Noise'};
                    handles.Options.Value = varargin{2};

                    %         OptStr = {'SSine';'FSine';'WNoise'};
                    OptStr = {'SSine'};

                    for j = 1:length(OptStr)

                        eval(['Srch = strfind(DSA_Conf.' OptStr{j} ',''SRLV '');']);
                        for i = 1:length(Srch)
                            if isempty(Srch{i})
                                Srch{i} = 0;
                            end
                        end
                        Srch = cell2mat(Srch);
                        if hndl.DSA_Input_Amp_Units.Value == 4 % Porcentaje sobre Ibias
                            Porc = str2double(hndl.DSA_Input_Amp.String)/100;
                            if ~handles1.SetupTES.LNCS_Active.Value
                                Ireal = handles1.SetupTES.Squid.Read_Current_Value;
                            else
                                Ireal = handles1.SetupTES.Squid.Read_Current_Value_LNCS;
                            end
                            SRLV_str = Ireal.Value*1e1*Porc;
                        else
                            Str = eval(['DSA_Conf.' OptStr{j} '{Srch == 1};']);
                            SRLV_str = Str(strfind(Str,'SRLV ')+5:end-2);
                        end
                        eval(['DSA_Conf.' OptStr{j} '{Srch == 1} = [''SRLV ' num2str(SRLV_str) 'mV''];'])
                    end

                    Srch = strfind(DSA_Conf.FSine,'FSIN ');
                    for i = 1:length(Srch)
                        if isempty(Srch{i})
                            Srch{i} = 0;
                        end
                    end
                    Srch = cell2mat(Srch);
                    DSA_Conf.FSine{Srch == 1} = ['FSIN ' varargin{3}.DSA_Input_Freq.String 'Hz'];


                    ConfInstrs{1} = DSA_Conf.SSine;
                    ConfInstrs{2} = DSA_Conf.FSine;
                    ConfInstrs{3} = DSA_Conf.WNoise;
                    % Configuration of the DSA options
                    handles.handles1 = handles1;
                    handles.ConfInstrs = ConfInstrs;
                    handles.Table.Data = handles.ConfInstrs{varargin{2}};

                case 'DSA_TF_Noise_Conf'
                    handles.figure1.Name = 'DSA Configuration';
                    hndl = guidata(varargin{1});
                    if isfield(hndl.SetupTES,'SetupTES')
                        DSA_Conf = hndl.SetupTES.DSA.Config;
                        handles1.SetupTES = hndl.SetupTES;
                    else
                        DSA_Conf = hndl.DSA.Config;
                        handles1.SetupTES = hndl;
                    end

                    ConfInstrs{1} = DSA_Conf.Noise;
                    handles.Options.String = {'Noise Setup'};
                    %         handles.Options.Value = varargin{2};
                    handles.Options.Value = 1;

                    % Noise Conf.
                    Srch = strfind(DSA_Conf.Noise,'SF ');
                    for i = 1:length(Srch)
                        if isempty(Srch{i})
                            Srch{i} = 0;
                        end
                    end
                    Srch = cell2mat(Srch);
                    DSA_Conf.Noise{Srch == 1} = ['SF ' varargin{3}.DSA_Input_Freq.String 'Hz'];
                    ConfInstrs{2} = DSA_Conf.Noise;
                    % Configuration of the DSA options
                    handles.ConfInstrs = ConfInstrs;

                    handles.Table.Data = handles.ConfInstrs{1};
                    handles.handles1 = handles1;

                case 'SQ_RangeIbias'
                    hndl = guidata(varargin{1});
                    handles.figure1.Name = 'Ibias Range Configuration';
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value';'Step';'Final Value'};
                    handles.Options.Visible = 'off';
                    handles.Table.Data = hndl.IbiasRange;
                case 'CurSource_Range'
                    hndl = guidata(varargin{1});
                    handles.figure1.Name = 'Field Range Configuration';
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value';'Step';'Final Value'};
                    handles.Options.Visible = 'off';
                    handles.Table.Data = hndl.FieldRange;
                case 'Param_Delay'
                    set(handles.figure1,'Name',handles.varargin{1}.UserData);
                    set([handles.Add handles.Remove handles.Options],'Visible','off');
                    handles.Table.Data = {[]};
                    handles.Table.ColumnEditable = [false true false];
                    TESProp = properties(handles.varargin{3});
                    handles.Table.ColumnName = {'Parameter';'Value';'Units'};
                    if length(TESProp) == 2
                        TESUnits = {'s';'s'};
                    else
                        TESUnits = {'s';'s';'';''};
                    end
                    %         handles.Table.Data(1:length(TESProp),1) = TESProp;
                    for i = 1:length(TESProp)
                        if ~isempty(eval(['handles.varargin{3}.' TESProp{i}]))
                            handles.Table.Data{i,1} = TESProp{i};
                            handles.Table.Data{i,2} = eval(['handles.varargin{3}.' TESProp{i}]);
                            handles.Table.Data{i,3} = TESUnits{i};
                        end
                    end
                case 'TES_Struct'
                    set(app.figure1,'Name','TES Circuit Parameters');
                    set([app.Add app.Remove app.Options],'Visible','off');
                    app.Table.ColumnName = {'Parameter';'Value';'Units'};
                    app.Table.ColumnEditable = [false true false];
                    CircProp = properties(app.varargin{3}.circuit);
                    ind = find(cellfun(@(s) ~isempty(strfind('CurrOffset', s)), CircProp)==1);
                    inds = [1:5 ind];
                    %         TESUnits = {'Ohm';'Ohm';'uA/phi';'uA/phi';'H';'pA/Hz^{0.5}'};
                    % app.Table.Data{1:6,1} = CircProp{inds};
                    for i = 1:6
                        app.Table.Data{i,1} = CircProp{inds(i)};
                        app.Table.Data{i,2} = eval(['app.varargin{3}.circuit.' CircProp{inds(i)} '.Value']);
                        app.Table.Data{i,3} = eval(['app.varargin{3}.circuit.' CircProp{inds(i)} '.Units']);
                    end
                case 'TES_ThermalParam'
                    set(handles.figure1,'Name',['TES Operating Point - ' handles.varargin{1}.Name]);
                    set([handles.Add handles.Remove handles.Options handles.Save],'Visible','off');
                    handles.Table.ColumnName = {'Parameter';'Value';'Units'};
                    handles.Table.ColumnEditable = [false false false];
                    TESProp = properties(handles.varargin{3});
                    %         TESUnits = {'adim';'adim';'W/K^n';'W/K^n';'K';'K';'W/K';'W/K';'W/K'};
                    handles.Table.Data(1:length(TESProp)-1,1) = TESProp(1:end-1);
                    handles.Table.Data(length(TESProp),1) = {['%' TESProp{end}]};
                    for i = 1:length(TESProp)
                        handles.Table.Data{i,2} = eval(['handles.varargin{3}.' TESProp{i} '.Value']);
                        handles.Table.Data{i,3} = eval(['handles.varargin{3}.' TESProp{i} '.Units']);
                    end
                case 'TES_Param'
                    set(handles.figure1,'Name',['TES Parameters - ' handles.varargin{1}.Name]);
                    set([handles.Add handles.Remove handles.Options handles.Save],'Visible','off');
                    handles.Table.ColumnName = {'Parameter';'Value';'Units'};
                    handles.Table.ColumnEditable = [false false false];
                    TESProp = properties(handles.varargin{3});
                    %         TESUnits = {'Ohm';'Ohm';'V/uA';'V/uA';'K';'#'};
                    handles.Table.Data(1:length(TESProp),1) = TESProp(1:length(TESProp));
                    for i = 1:length(TESProp)
                        handles.Table.Data{i,2} = eval(['handles.varargin{3}.' TESProp{i} '.Value']);
                        handles.Table.Data{i,3} = eval(['handles.varargin{3}.' TESProp{i} '.Units']);
                    end
                case 'TES_TF_Opt'
                    set(handles.figure1,'Name','TF Visualization Options');
                    set([handles.Add handles.Remove handles.Options],'Visible','off');
                    handles.Table.Data = {[]};
                    handles.Table.ColumnEditable = [true true true];
                    TESProp = properties(handles.varargin{3});
                    handles.Table.ColumnName = TESProp';
                    handles.Table.ColumnFormat{1} = 'Logical';
                    handles.Table.ColumnFormat{2} = {'\TF*','\PXI_TF*'};
                    handles.Table.ColumnFormat{3} = {'One Single Thermal Block','Two Thermal Blocks'};

                    for i = 1:length(TESProp)
                        if strcmp(handles.Table.ColumnFormat{i},'Logical')
                            if eval(['handles.varargin{3}.' TESProp{i}])
                                handles.Table.Data{1,i} = true;
                            else
                                handles.Table.Data{1,i} = false;
                            end
                        else
                            handles.Table.Data{1,i} = eval(['handles.varargin{3}.' TESProp{i}]);
                        end
                    end
                case 'TES_ElectrThermModel'
                    set(app.figure1,'Name','Electro-Thermal Model Z(w) and Noise Fitting Options');
                    set([app.Add app.Remove app.Options],'Visible','off');
                    app.Table.Data = {[]};

                    TESProp = properties(app.varargin{3});
                    j = 1;
                    for i = 1:length(TESProp)
                        if strfind(TESProp{i},'Selected')
                            cellList = eval(['app.varargin{3}.' TESProp{i-1}])';
                            app.Table.Data{1,j} = char(cellList(eval(['app.varargin{3}.' TESProp{i}])));
                            j = j+1;
                        elseif strfind(TESProp{i},'bool')
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = 'Logical';
                            app.Table.ColumnName{j} = TESProp{i};
                            if eval(['app.varargin{3}.' TESProp{i}])
                                app.Table.Data{1,j} = true;
                            else
                                app.Table.Data{1,j} = false;
                            end
                            j = j+1;
                        elseif iscell(eval(['app.varargin{3}.' TESProp{i}]))
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = eval(['app.varargin{3}.' TESProp{i}])';
                            app.Table.ColumnName{j} = TESProp{i};

                        elseif isnumeric(eval(['app.varargin{3}.' TESProp{i}]))
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = 'numeric';
                            app.Table.ColumnName{j} = TESProp{i};
                            if size(eval(['app.varargin{3}.' TESProp{i}]),2) == 1
                                app.Table.Data{1,j} = eval(['app.varargin{3}.' TESProp{i}]);
                            else
                                app.Table.Data{1,j} = num2str(eval(['app.varargin{3}.' TESProp{i}]));
                            end
                            j = j+1;
                        end

                    end

                case 'TES_PvTModel'
                    set(app.figure1,'Name','P vs T Model Fitting Options');
                    set([app.Add app.Remove app.Options],'Visible','off');
                    app.Table.Data = {[]};

                    TESProp = properties(app.varargin{3});
                    j = 1;
                    for i = 1:length(TESProp)
                        if strfind(TESProp{i},'Selected')
                            cellList = eval(['app.varargin{3}.' TESProp{i-1}])';
                            app.Table.Data{1,j} = char(cellList(eval(['app.varargin{3}.' TESProp{i}])));
                            j = j+1;
                        elseif strfind(TESProp{i},'bool')
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = 'Logical';
                            app.Table.ColumnName{j} = TESProp{i};
                            if eval(['app.varargin{3}.' TESProp{i}])
                                app.Table.Data{1,j} = true;
                            else
                                app.Table.Data{1,j} = false;
                            end
                            j = j+1;
                        elseif iscell(eval(['app.varargin{3}.' TESProp{i}]))
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = eval(['app.varargin{3}.' TESProp{i}])';
                            app.Table.ColumnName{j} = TESProp{i};

                        elseif isnumeric(eval(['app.varargin{3}.' TESProp{i}]))
                            app.Table.ColumnEditable(j) = true;
                            app.Table.ColumnFormat{j} = 'numeric';
                            app.Table.ColumnName{j} = TESProp{i};
                            app.Table.Data{1,j} = num2str(eval(['app.varargin{3}.' TESProp{i}]));
                            j = j+1;
                        end

                    end

                case 'TES_Noise_Opt'
                    set(handles.figure1,'Name','Noise Visualization Options');
                    set([handles.Add handles.Remove handles.Options],'Visible','off');
                    handles.Table.Data = {[]};
                    TESProp = properties(handles.varargin{3});
                    handles.Table.ColumnName = TESProp';
                    handles.Table.ColumnFormat{1} = {'current','nep'};
                    handles.Table.ColumnFormat{2} = 'Logical';
                    handles.Table.ColumnFormat{3} = 'Logical';
                    handles.Table.ColumnFormat{4} = 'Logical';
                    handles.Table.ColumnFormat{5} = {'\HP_noise*','\PXI_noise*'};
                    handles.Table.ColumnFormat{6} = {'irwin','wouter'};
                    handles.Table.ColumnFormat{7} = 'numeric';
                    handles.Table.ColumnFormat{8} = 'numeric';
                    handles.Table.ColumnFormat{9} = 'numeric';
                    handles.Table.ColumnEditable = [true true true true true true true true true];

                    for i = 1:length(TESProp)
                        if strcmp(handles.Table.ColumnFormat{i},'Logical')
                            if eval(['handles.varargin{3}.' TESProp{i}])
                                handles.Table.Data{1,i} = true;
                            else
                                handles.Table.Data{1,i} = false;
                            end
                        else
                            handles.Table.Data{1,i} = eval(['handles.varargin{3}.' TESProp{i}]);
                        end
                    end
                case 'TES_Report_Opt'
                    set(app.figure1,'Name','Report Options');
                    set([app.Add app.Remove app.Options],'Visible','off');
                    app.Table.Data = {[]};
                    TESProp = properties(app.varargin{3});
                    app.Table.ColumnName = TESProp';
                    for i = 1:length(TESProp)
                        app.Table.ColumnFormat{i} = 'Logical';
                        app.Table.ColumnEditable(i) = true;
                        if strcmp(app.Table.ColumnFormat{i},'Logical')
                            if eval(['app.varargin{3}.' TESProp{i}])
                                app.Table.Data{1,i} = true;
                            else
                                app.Table.Data{1,i} = false;
                            end
                        else
                            app.Table.Data{1,i} = eval(['app.varargin{3}.' TESProp{i}]);
                        end
                    end

                case 'AQ_Temp_Set'
                    set(handles.figure1,'Name','Set Temperatures');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(K)';'Step(K)';'Final Value(K)'};
                    handles.Table.Data = {[]};
                    %         handles.Table.Data = num2cell(hndl.Temp.Values);
                    handles.Table.Data = cell(1,3);
                    try
                        handles.Table.Data(1:size(hndl.Temp.Values,1),size(hndl.Temp.Values,2)) = num2cell(hndl.Temp.Values');
                    end
                    handles.Options.Visible = 'off';
                case 'AQ_FieldScan_Set'
                    set(handles.figure1,'Name','Set Field Value for Scan (max Vout)');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(uA)';'Step(uA)';'Final Value(uA)'};
                    handles.Table.Data = cell(1,3);
                    try
                        handles.Table.Data(1:size(hndl.FieldScan.BVvalues,1),size(hndl.FieldScan.BVvalues,2)) = num2cell(hndl.FieldScan.BVvalues');
                    end
                    handles.Options.Visible = 'off';
                case 'AQ_IC_Field_Set'
                    set(handles.figure1,'Name','Set Field Values for Critical Currents');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(uA)';'Step(uA)';'Final Value(uA)'};
                    handles.Table.Data = cell(1,3);
                    try
                        handles.Table.Data(1:size(hndl.BFieldIC.BVvalue,1),size(hndl.BFieldIC.BVvalue,2)) = num2cell(hndl.BFieldIC.BVvalue');
                    catch
                    end
                    handles.Options.Visible = 'off';
                case 'Ibias_Set'
                    set(handles.figure1,'Name','Set Ibias Values for IV Curves');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(uA)';'Step(uA)';'Final Value(uA)'};
                    handles.Table.Data = cell(1,3);
                    handles.Table.Data = [hndl.IVcurves.Manual.Values.p; hndl.IVcurves.Manual.Values.n];
                    handles.Options.Visible = 'off';
                case 'BFieldIC_Ibias'
                    set(handles.figure1,'Name','Set Ibias Values for Critical Currents');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(uA)';'Step(uA)';'Final Value(uA)'};
                    handles.Table.Data = cell(1,3);
                    handles.Table.Data = [hndl.BFieldIC.IbiasValues.p; hndl.BFieldIC.IbiasValues.n];
                    handles.Options.Visible = 'off';
                case 'AQ_TF_Rn_P_Set'
                    set(handles.figure1,'Name','Set % of Rn Values for Z(w) and Noise Adquisition');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(%)';'Step(%)';'Final Value(%)'};
                    handles.Table.Data = cell(1,3);
                    try
                        handles.Table.Data(1:size(hndl.TF_Zw.rpp,1),size(hndl.TF_Zw.rpp,2)) = num2cell(hndl.TF_Zw.rpp);
                    end
                    handles.Options.Visible = 'off';
                case 'AQ_TF_Rn_N_Set'
                    set(handles.figure1,'Name','Set % of Rn Values for Z(w) and Noise Adquisition');
                    hndl = guidata(varargin{1});
                    handles.Table.ColumnEditable = [true true true];
                    handles.Table.ColumnName = {'Initial Value(%)';'Step(%)';'Final Value(%)'};
                    handles.Table.Data = cell(1,3);
                    try
                        handles.Table.Data(1:size(hndl.TF_Zw.rpn,1),size(hndl.TF_Zw.rpn,2)) = num2cell(hndl.TF_Zw.rpn);
                    end
                    handles.Options.Visible = 'off';
            end
            % Update handles structure
            guidata(app.figure1, app);

        end

        % Button down function: figure1
        function figure1ButtonDown(app, event)
            % allfigs = findall(0, 'Type', 'figure');
            % appHandle = findall(allfigs, 'Name', 'TF_Viewer');
            % if ~isempty(appHandle)
            %     hndl = appHandle(1).RunningAppInstance;
            % end

            cmenu = uicontextmenu(app.figure1,'Visible','on');

            % cmenu = uicontextmenu('Visible','on');
            c1 = uimenu(cmenu,'Label','Export');

            uimenu(c1,'Label','Data to a .txt file','Callback',...
                {@ExportDataTXT});
            guidata(c1,guidata(event.Source));

            app.figure1.ContextMenu = cmenu;
            % pause(0.001)
            open(cmenu,cmenu.Position);
            set(app.figure1,'uicontextmenu',cmenu,'Visible','on');
            % set(src,'uicontextmenu',cmenu);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app.figure1.ContextMenu);
            delete(app)
            
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            handles = app;
            switch handles.varargin{1}.Tag
                case 'Squid_Pulse_Input_Conf'
                    handles.varargin{1}.UserData = handles.Table.Data;
                case 'DSA_TF_Zw_Conf'
                    handles.varargin{3}.TF_Menu.Value = handles.Options.Value;
                    handles.handles1.SetupTES.DSA.Config.SSine = handles.ConfInstrs{1};
                    handles.handles1.SetupTES.DSA.Config.FSine = handles.ConfInstrs{2};
                    handles.handles1.SetupTES.DSA.Config.WNoise = handles.ConfInstrs{3};
                    guidata(handles.handles1.SetupTES.SetupTES,handles.handles1.SetupTES)

                case 'DSA_TF_Noise_Conf'
                    handles.varargin{3}.Noise_Menu.Value = handles.Options.Value;
                    handles.handles1.SetupTES.DSA.Config.Noise = handles.ConfInstrs{1};
                    guidata(handles.handles1.SetupTES.SetupTES,handles.handles1.SetupTES)

                case 'SQ_RangeIbias'
                    handles.varargin{1}.UserData = handles.Table.Data;
                case 'CurSource_Range'
                    handles.varargin{1}.UserData = handles.Table.Data;

                case 'Param_Delay'
                    NewOpt = IV_Delay;
                    ParamDelay = properties(handles.varargin{3});
                    for i = 1:length(ParamDelay)
                        try
                            eval(['NewOPT.' ParamDelay{i} ' = handles.Table.Data{i,2};']);%
                        catch
                        end
                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);
                case 'TES_Struct'
                    ButtonName = questdlg('Do you want to save these Circuit parameters?', ...
                        handles.VersionStr, ...
                        'Save', 'Cancel', 'Save');
                    switch ButtonName
                        case 'Save'
                            CircProp = properties(handles.varargin{3}.circuit);
                            ind = find(cellfun(@(s) ~isempty(strfind('CurrOffset', s)), CircProp)==1);
                            inds = [1:5 ind];
                            for i = 1:length(inds)
                                try
                                    if ~ischar(handles.Table.Data{i,2})
                                        eval(['NewCircuit.' CircProp{inds(i)} ' = handles.Table.Data{i,2};']);
                                    else
                                        eval(['NewCircuit.' CircProp{inds(i)} ' = str2double(handles.Table.Data{i,2});']);
                                    end
                                catch
                                end
                            end
                            handles.varargin{1}.UserData = NewCircuit;
                            guidata(handles.varargin{1},NewCircuit);
                    end % switch

                case 'TES_PvTModel'
                    TESProp = properties(handles.varargin{3});
                    j = 1;
                    for i = 1:length(TESProp)
                        if strfind(TESProp{i},'Selected')
                            c = strfind(eval(['handles.varargin{3}.' TESProp{i-1}]),handles.Table.Data{1,j});
                            for c1 = 1:length(c)
                                if c{c1} == 1
                                    break;
                                end
                            end
                            NewOPT = TES_PvTModel;
                            eval(['NewOPT.Selected_Models = ' num2str(c1) ';']);
                            NewOPT = NewOPT.Constructor;
                            j = j+1;
                        elseif i >= 5
                            if ~isempty(handles.Table.Data{1,j})
                                s1 = double(handles.Table.Data{1,j});
                                sj = 1;
                                while ~isempty(s1)
                                    [cs, s1] = strtok(s1,32);
                                    eval(['NewOPT.' TESProp{i} '(sj) = ' char(cs) ';']);
                                    sj = sj+1;
                                end
                            end
                            j = j+1;
                        end
                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);

                case  'TES_ElectrThermModel'
                    TES_TF = properties(handles.varargin{3});

                    for i = 1:length(handles.Table.ColumnName)
                        if strfind(handles.Table.ColumnName{i},'bool')
                            if handles.Table.Data{1,i}
                                eval(['NewOPT.' handles.Table.ColumnName{i} ' = 1;']);
                            else
                                eval(['NewOPT.' handles.Table.ColumnName{i} ' = 0;']);
                            end
                        elseif ischar(handles.Table.Data{1,i})
                            List = eval(['handles.varargin{3}.' handles.Table.ColumnName{i}]);
                            [s, s1] = strtok(handles.Table.Data{1,i});
                            if ~isnan(str2double(s))&&~isnan(str2double(s1))
                                eval(['NewOPT.' handles.Table.ColumnName{i} ' = [str2double(s) str2double(s1)];']);
                            else
                                try
                                    ind = strfind(List,handles.Table.Data{1,i});
                                    Index = find(not(cellfun('isempty',ind)));
                                    if length(Index) > 1
                                        for id = 1:length(Index)
                                            if isequal(List{Index(id)},handles.Table.Data{1,i})
                                                Index = Index(id);
                                                break;
                                            end
                                        end
                                    end
                                    eval(['NewOPT.Selected_' handles.Table.ColumnName{i} ' = Index;']);
                                catch
                                    eval(['NewOPT.' handles.Table.ColumnName{i} ' = List;']);
                                end
                            end
                        else
                            eval(['NewOPT.' handles.Table.ColumnName{i} ' = handles.Table.Data{1,i};']);
                        end

                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);

                case 'TES_TF_Opt'
                    TES_TF = properties(handles.varargin{3});
                    for i = 1:length(TES_TF)
                        if strcmp(handles.Table.ColumnFormat{i},'Logical')
                            if handles.Table.Data{1,i}
                                eval(['NewOPT.' TES_TF{i} ' = 1;']);
                            else
                                eval(['NewOPT.' TES_TF{i} ' = 0;']);
                            end
                        else
                            eval(['NewOPT.' TES_TF{i} ' = handles.Table.Data{1,i};']);
                        end
                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);

                case 'TES_Noise_Opt'
                    TES_Noise = properties(handles.varargin{3});
                    for i = 1:length(TES_Noise)
                        if strcmp(handles.Table.ColumnFormat{i},'Logical')
                            if handles.Table.Data{1,i}
                                eval(['NewOPT.' TES_Noise{i} ' = 1;']);
                            else
                                eval(['NewOPT.' TES_Noise{i} ' = 0;']);
                            end
                        else
                            eval(['NewOPT.' TES_Noise{i} ' = handles.Table.Data{1,i};']);
                        end
                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);
                case 'TES_Report_Opt'
                    TES_Report = properties(handles.varargin{3});
                    for i = 1:length(TES_Report)
                        if strcmp(handles.Table.ColumnFormat{i},'Logical')
                            if handles.Table.Data{1,i}
                                eval(['NewOPT.' TES_Report{i} ' = 1;']);
                            else
                                eval(['NewOPT.' TES_Report{i} ' = 0;']);
                            end
                        else
                            eval(['NewOPT.' TES_Report{i} ' = handles.Table.Data{1,i};']);
                        end
                    end
                    handles.varargin{1}.UserData = NewOPT;
                    guidata(handles.varargin{1},NewOPT);

                case 'AQ_Temp_Set'
                    Temp = ExtractFromTable(handles);
                    if size(Temp{1},2) > size(Temp{1},1)
                        Temp{1} = Temp{1}';
                    end
                    handles.varargin{1}.UserData = Temp;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));

                case 'AQ_FieldScan_Set'
                    Val = ExtractFromTable(handles);
                    if size(Val{1},2) > size(Val{1},1)
                        Val{1} = Val{1}';
                    end
                    handles.varargin{1}.UserData = Val;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));
                case 'AQ_IC_Field_Set'
                    Val = ExtractFromTable(handles);
                    if size(Val{1},2) > size(Val{1},1)
                        Val{1} = Val{1}';
                    end
                    handles.varargin{1}.UserData = Val;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));
                case 'Ibias_Set'
                    Val = ExtractFromTable(handles);
                    if size(Val{1},2) > size(Val{1},1)
                        Val{1} = Val{1}';
                    end
                    handles.varargin{1}.UserData = Val;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));
                case 'BFieldIC_Ibias'
                    Val = ExtractFromTable(handles);
                    if size(Val{1},2) > size(Val{1},1)
                        Val{1} = Val{1}';
                    end
                    handles.varargin{1}.UserData = Val;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));
                case 'AQ_TF_Rn_P_Set'
                    rpp = ExtractFromTable(handles);
                    if size(rpp{1},2) > size(rpp{1},1)
                        rpp{1} = rpp{1}';
                    end
                    handles.varargin{1}.UserData = rpp;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));

                case 'AQ_TF_Rn_N_Set'
                    rpn = ExtractFromTable(handles);
                    if size(rpn{1},2) > size(rpn{1},1)
                        rpn{1} = rpn{1}';
                    end
                    handles.varargin{1}.UserData = rpn;
                    guidata(handles.varargin{1},guidata(handles.varargin{1}));

            end

            app.figure1CloseRequest;
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            handles = app;
            handles.varargin{1}.UserData = [];
            app.figure1CloseRequest;
        end

        % Cell edit callback: Table
        function TableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            handles = app;
            switch handles.varargin{1}.Tag
                case 'DSA_TF_Zw_Conf'
                    handles.varargin{3}.DSA_TF_Zw_Menu.Value = handles.Options.Value;
                    handles.ConfInstrs{handles.Options.Value} = handles.Table.Data;
                    guidata(event.Source,app);
                case 'DSA_TF_Noise_Conf'
                    handles.varargin{3}.DSA_TF_Zw_Menu.Value = handles.Options.Value;
                    handles.ConfInstrs{handles.Options.Value} = handles.Table.Data;
                    guidata(event.Source,app);
                case ''
                    handles.Params = handles.Table.Data(:,2);
                    guidata(event.Source,app);
                case 'TES_ElectrThermModel'
                    handles.Params = handles.Table.Data(:,2);
                    guidata(event.Source,app);
                case 'TES_PvTModel'
                    if isequal(event.Indices,[1,1])&& ~strcmp(event.PreviousData,event.NewData)
                        ind = strfind(handles.varargin{3}.Models,char(event.NewData));
                        c = true;
                        j = 1;
                        while c
                            if ~isempty(ind{j})
                                c = false;
                                break;
                            end
                            j = j+1;
                        end
                        handles.varargin{3}.Selected_Models = j;
                        handles.varargin{3} = handles.varargin{3}.BuildPTbModel;
                        TESProp = properties(handles.varargin{3});
                        j = 1;
                        for i = 1:length(TESProp)
                            if strfind(TESProp{i},'Selected')
                                cellList = eval(['handles.varargin{3}.' TESProp{i-1}])';
                                handles.Table.Data{1,j} = char(cellList(eval(['handles.varargin{3}.' TESProp{i}])));
                                j = j+1;
                            elseif strfind(TESProp{i},'bool')
                                handles.Table.ColumnEditable(j) = true;
                                handles.Table.ColumnFormat{j} = 'Logical';
                                handles.Table.ColumnName{j} = TESProp{i};
                                if eval(['handles.varargin{3}.' TESProp{i}])
                                    handles.Table.Data{1,j} = true;
                                else
                                    handles.Table.Data{1,j} = false;
                                end
                                j = j+1;
                            elseif iscell(eval(['handles.varargin{3}.' TESProp{i}]))
                                handles.Table.ColumnEditable(j) = true;
                                handles.Table.ColumnFormat{j} = eval(['handles.varargin{3}.' TESProp{i}])';
                                handles.Table.ColumnName{j} = TESProp{i};

                            elseif isnumeric(eval(['handles.varargin{3}.' TESProp{i}]))
                                handles.Table.ColumnEditable(j) = true;
                                handles.Table.ColumnFormat{j} = 'numeric';
                                handles.Table.ColumnName{j} = TESProp{i};
                                handles.Table.Data{1,j} = num2str(eval(['handles.varargin{3}.' TESProp{i}]));
                                j = j+1;
                            end

                        end
                    end
                otherwise
                    if ischar(event.Source.Data{event.Indices(1),event.Indices(2)})
                        event.Source.Data{event.Indices(1),event.Indices(2)} = str2double(event.Source.Data{event.Indices(1),event.Indices(2)});
                    else
                        event.Source.Data{event.Indices(1),event.Indices(2)} = event.Source.Data{event.Indices(1),event.Indices(2)};
                    end
                    if ~isempty(strfind(handles.varargin{1}.Tag,'_Rn_'))
                        if (str2double(event.NewData) > 1)||(str2double(event.NewData) < 0)
                            msgbox('Rn(%) Values must be between 0 and 1',handles.VersionStr);
                        end
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Conf_Setup';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.ButtonDownFcn = createCallbackFcn(app, @figure1ButtonDown, true);
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.Table = uitable(app.Panel);
            app.Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table.RowName = {};
            app.Table.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.Table.Tag = 'Table';
            app.Options = uidropdown(app.figure1);
            app.Options.Items = {};
            app.Options.Tag = 'Options';
            app.Options.Value = {};
            app.Add = uibutton(app.figure1, 'push');
            app.Add.Tag = 'Add';
            app.Add.Text = '+';
            app.Remove = uibutton(app.figure1, 'push');
            app.Remove.Tag = 'Remove';
            app.Remove.Text = '-';
            app.Save = uibutton(app.figure1, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Tag = 'Save';
            app.Save.Text = 'Save/Apply';
            app.Cancel = uibutton(app.figure1, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Tag = 'Cancel';
            app.Cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppConf_Setup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end