classdef AppElectNoise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        ButtonGroup           matlab.ui.container.ButtonGroup
        LoadedFile            matlab.ui.control.Label
        Cancel                matlab.ui.control.Button
        Save                  matlab.ui.control.Button
        ButtonGroup_2         matlab.ui.container.ButtonGroup
        LoadedModelFile       matlab.ui.control.Label
        ModelSingleChoose     matlab.ui.control.ListBox
        ModelSingleValue      matlab.ui.control.NumericEditField
        pAHz12Label           matlab.ui.control.Label
        ModelView             matlab.ui.control.Button
        ModelFromFile         matlab.ui.control.Button
        ModelSingle           matlab.ui.control.RadioButton
        ModelArray            matlab.ui.control.RadioButton
        ElectValue            matlab.ui.control.NumericEditField
        pAHz12EditFieldLabel  matlab.ui.control.Label
        View                  matlab.ui.control.Button
        FromFile              matlab.ui.control.Button
        ModelBased            matlab.ui.control.RadioButton
        ManualSingle          matlab.ui.control.RadioButton
        ManualArray           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        varargin % Description
        VersionStr
        circuitNoise
        h
    end
    
    methods (Access = private)
        
        function InicialConf(app,ElectricalNoise)
            
            switch ElectricalNoise.Selected_Tipo
                case 1 % Value
                    set([app.LoadedModelFile app.LoadedModelFile],'Text','');
                    if ElectricalNoise.ModelBased == 0
                        set(app.ElectValue,'Value',ElectricalNoise.Value*1e12);
                        app.ManualSingle.Value = 1;
                    else
                        app.ModelBased.Value = 1;
                        app.ModelSingle.Value = 1;
                        set(app.ModelSingleValue,'Value',ElectricalNoise.Value*1e12);
                        set(app.LoadedModelFile,'Text',num2str(ElectricalNoise.File));
                    end


                case 2 % Array
                    if ElectricalNoise.ModelBased == 0
                        app.ManualArray.Value = 1;
                        set(app.LoadedModelFile,'Text',ElectricalNoise.File);
                        set(app.LoadedModelFile,'Text','');
                    else
                        app.ModelBased.Value = 1;
                        app.ModelArray.Value = 1;
                        set(app.LoadedModelFile,'Text',num2str(ElectricalNoise.File));
                        set(app.LoadedModelFile,'Text','');
                    end
                    % set(app.ElectValue,'Value',);

            end
            
        end

        function  habilitar(app)            
            ManAr = get(app.ManualArray,'Value');
            ManSin = get(app.ManualSingle,'Value');
            ModBas = get(app.ModelBased,'Value');
            BotonesPrimarios = sum([ManAr ManSin*2 ModBas*3]);

            switch BotonesPrimarios
                case 1 % Manual Array
                    set([app.ElectValue],'Enable','off');
                    set(app.FromFile,'Enable','on');
                    set([app.ModelSingleValue app.ModelFromFile app.ModelSingleChoose],'Enable','off');
                    %         ManualArray_Callback(app.ManualArray,[],app);
                case 2 % Manual Single
                    set([app.FromFile app.View],'Enable','off');
                    set(app.ElectValue,'Enable','on');
                    set([app.ModelSingleValue app.ModelFromFile app.ModelSingleChoose],'Enable','off');
                    %         ManualSingle_Callback(app.ManualArray,[],app);
                case 3 % Model Based
                    set([app.FromFile app.View app.ElectValue],'Enable','off');
                    %         ModelBased_Callback(app.ModelBased,[],app);
                    if app.ModelArray.Value
                        set(app.ModelFromFile,'Enable','on');
                        set([app.ModelSingleValue app.ModelSingleChoose],'Enable','off');
                        %             ModelArray_Callback(app.ModelArray,[],app);
                    elseif app.ModelSingle.Value
                        set([app.ModelSingleValue app.ModelSingleChoose],'Enable','on');
                        set(app.ModelFromFile,'Enable','off');
                        %             ModelSingle_Callback(app.ModelArray,[],app);
                    end
                otherwise

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.varargin = varargin;
            app.figure1.Units = 'normalized';
            position = get(app.figure1,'Position');
            set(app.figure1,'Color',[0 120 180]/255,'Position',...
                [0.5-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');

            app.VersionStr = app.varargin{1}.version;
            set(app.figure1,'Name',['Electrical Noise Identification    ---   ' app.VersionStr]);

            ElectricalNoise = app.varargin{1}.circuitNoise;

            app.InicialConf(ElectricalNoise);
            app.habilitar;
            app.circuitNoise = app.varargin{1}.circuitNoise;
            app.h = app.varargin{2};

            guidata(app.figure1, app);
        end

        % Value changed function: ElectValue
        function ElectValueValueChanged(app, event)
            num = app.ElectValue.Value*1e-12;
            if ~isnumeric(num)
                warndlg('Incorrect number value',app.VersionStr);
                return;
            end
            app.circuitNoise.Value = num;
            app.circuitNoise.Array = [];
            app.circuitNoise.NoFiltArray = [];
            app.circuitNoise.File = [];
            app.circuitNoise.Selected_Tipo = 1;
            app.circuitNoise.ModelBased = 0;
            set(app.View,'Enable','off');
            guidata(event.Source,app);
        end

        % Button pushed function: FromFile
        function FromFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.txt', 'Pick a txt file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel');
                return;
            end
            Data = importdata([pathname filesep filename]);
            fNoise = Data(:,1);
            SigNoise = Data(:,2);

            % f = logspace(1,5,321)';
            f=logspace(0,6,1001);

            SigNoise = spline(fNoise,SigNoise,f);
            SigNoise = app.varargin{1}.V2I(SigNoise);
            app.circuitNoise.NoFiltArray = SigNoise;
            % Filtrar ruido

            SigNoise = app.varargin{1}.ElectrThermalModel.NoiseFiltering(app.circuitNoise.NoFiltArray);

            app.circuitNoise.Array = SigNoise;
            app.circuitNoise.File = [pathname filesep filename];
            app.circuitNoise.Selected_Tipo = 1;
            app.circuitNoise.Value = [];
            app.circuitNoise.ModelBased = 0;

            set(app.LoadedFile,'Text',app.circuitNoise.File);

            set(app.View,'Enable','on');
            guidata(event.Source,app);
        end

        % Button pushed function: View
        function ViewButtonPushed(app, event)
            figure;
            loglog(logspace(0,6,1001),app.circuitNoise.NoFiltArray,'DisplayName','No Filtered Noise');
            hold on;
            loglog(logspace(0,6,1001),app.circuitNoise.Array,'DisplayName','Filtered Noise');
            xlabel('Freq (Hz)','FontUnits','normalized','FontSize',0.08);
            ylabel('Amplitude (A/{Hz}^{1/2})','FontUnits','normalized','FontSize',0.08);
            set(gca,'Yscale','log','Xscale','log')
        end

        % Button pushed function: ModelFromFile
        function ModelFromFileButtonPushed(app, event)

            Noise = TES_BasalNoises;

            [filename, pathname] = uigetfile('*.txt', 'Pick a txt file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel')
                return;
            end
            fig = figure('Visible','off');
            Noise = Noise.NoisefromFile([pathname filename],fig,app.varargin{1});

            % Filtrar la señal de ruido
            app.circuitNoise.NoFiltArray = Noise.SigNoise;
            % Filtrar ruido

            Noise.SigNoise = app.varargin{1}.ElectrThermalModel.NoiseFiltering(app.circuitNoise.NoFiltArray);


            Tbath = str2double(Noise.fileNoise(find(Noise.fileNoise == '_', 1, 'last')+1:strfind(Noise.fileNoise,'mK.txt')-1));
            if ~isnumeric(Tbath)
                msgbox('No valid Tbath value was automatically detected from file name',app.VersionStr);
                Tbath = [];
            end

            reply = inputdlg('Introduce a Tbath (mK) value',app.VersionStr,[1 50],{num2str(Tbath)});
            %     reply = input('Introduce a Tbath value','50mK');
            if isempty(reply)
                warndlg('No Tbath value selected',app.VersionStr);
                return;
            end
            Tbath = str2double(reply)*1e-3;
            if ~isnumeric(Tbath)
                warndlg('Invalid Tbath value',app.VersionStr);
                return;
            end
            [~,N,Noise] = Noise.NnoiseModel(app.varargin{1},Tbath);
            app.circuitNoise.Array = N;
            app.circuitNoise.Value = [];
            app.circuitNoise.File = Noise.fileNoise;
            app.circuitNoise.Selected_Tipo = 2;
            app.circuitNoise.ModelBased = 1;
            set(app.LoadedModelFile,'Text',app.circuitNoise.File);

            set(app.ModelView,'Enable','on');

            guidata(event.Source,app);
        end

        % Button pushed function: ModelView
        function ModelViewButtonPushed(app, event)
            figure;
            loglog(logspace(0,6,1001),app.circuitNoise.NoFiltArray*1e-12,'DisplayName','No Filtered Noise');
            hold on;
            loglog(logspace(0,6,1001),app.circuitNoise.Array,'DisplayName','Model Based Noise');
            xlabel('Freq (Hz)','FontUnits','normalized','FontSize',0.08);
            ylabel('Amplitude (A/{Hz}^{1/2})','FontUnits','normalized','FontSize',0.08);
            set(gca,'Yscale','log','Xscale','log')
        end

        % Value changed function: ModelSingleValue
        function ModelSingleValueValueChanged(app, event)
            % value = app.ModelSingleValue.Value;
            app.ElectricalNoise.ModelBased = 1;
            guidata(event.Source,app);
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            app.h.UserData= app.circuitNoise;
            close(app.figure1);
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            close(app.figure1);
        end

        % Value changed function: ModelSingleChoose
        function ModelSingleChooseValueChanged(app, event)
            value = app.ModelSingleChoose.Value;
            if ~isempty(app.circuitNoise.File)
                switch value
                    case 1 %Mean value
                        app.circuitNoise.Value = nanmean(app.circuitNoise.Array);
                    case 2 %Median value
                        app.circuitNoise.Value = nanmedian(app.circuitNoise.Array);
                    case 3 %Mode value
                        app.circuitNoise.Value = mode(app.circuitNoise.Array);
                end

                app.ModelSingleValue.String = num2str(app.circuitNoise.Value*1e12);
                app.circuitNoise.Selected_Tipo = 1;
            end

            guidata(event.Source,app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.habilitar;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.habilitar;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Tag = 'figure1';
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ManualArray = uiradiobutton(app.ButtonGroup);
            app.ManualArray.Tag = 'ManualArray';
            app.ManualArray.Text = 'Manual Array Value';
            app.ManualSingle = uiradiobutton(app.ButtonGroup);
            app.ManualSingle.Tag = 'ManualSingle';
            app.ManualSingle.Text = 'Manual Single Value';
            app.ManualSingle.Value = true;
            app.ModelBased = uiradiobutton(app.ButtonGroup);
            app.ModelBased.Tag = 'ModelBased';
            app.ModelBased.Text = 'Model Based';
            app.FromFile = uibutton(app.ButtonGroup, 'push');
            app.FromFile.ButtonPushedFcn = createCallbackFcn(app, @FromFileButtonPushed, true);
            app.FromFile.Tag = 'FromFile';
            app.FromFile.Text = 'From File';
            app.View = uibutton(app.ButtonGroup, 'push');
            app.View.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.View.Tag = 'View';
            app.View.Text = 'PreView';
            app.pAHz12EditFieldLabel = uilabel(app.ButtonGroup);
            app.pAHz12EditFieldLabel.Text = 'pA/{Hz}^{1/2}';
            app.ElectValue = uieditfield(app.ButtonGroup, 'numeric');
            app.ElectValue.Limits = [0 Inf];
            app.ElectValue.ValueChangedFcn = createCallbackFcn(app, @ElectValueValueChanged, true);
            app.ElectValue.Tag = 'ElectValue';
            app.ElectValue.Value = 6;
            app.ButtonGroup_2 = uibuttongroup(app.ButtonGroup);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ModelArray = uiradiobutton(app.ButtonGroup_2);
            app.ModelArray.Tag = 'ModelArray';
            app.ModelArray.Text = 'Array Value';
            app.ModelSingle = uiradiobutton(app.ButtonGroup_2);
            app.ModelSingle.Tag = 'ModelSingle';
            app.ModelSingle.Text = 'Single Value';
            app.ModelSingle.Value = true;
            app.ModelFromFile = uibutton(app.ButtonGroup_2, 'push');
            app.ModelFromFile.ButtonPushedFcn = createCallbackFcn(app, @ModelFromFileButtonPushed, true);
            app.ModelFromFile.Tag = 'ModelFromFile';
            app.ModelFromFile.Text = 'From File';
            app.ModelView = uibutton(app.ButtonGroup_2, 'push');
            app.ModelView.ButtonPushedFcn = createCallbackFcn(app, @ModelViewButtonPushed, true);
            app.ModelView.Tag = 'ModelView';
            app.ModelView.Text = 'PreView';
            app.pAHz12Label = uilabel(app.ButtonGroup_2);
            app.pAHz12Label.Text = 'pA/{Hz}^{1/2}';
            app.ModelSingleValue = uieditfield(app.ButtonGroup_2, 'numeric');
            app.ModelSingleValue.Limits = [0 Inf];
            app.ModelSingleValue.ValueChangedFcn = createCallbackFcn(app, @ModelSingleValueValueChanged, true);
            app.ModelSingleValue.Tag = 'ModelSingleValue';
            app.ModelSingleValue.Value = 6;
            app.ModelSingleChoose = uilistbox(app.ButtonGroup_2);
            app.ModelSingleChoose.Items = {'Mean', 'Median'};
            app.ModelSingleChoose.ValueChangedFcn = createCallbackFcn(app, @ModelSingleChooseValueChanged, true);
            app.ModelSingleChoose.Tag = 'ModelSingleChoose';
            app.ModelSingleChoose.Value = 'Mean';
            app.LoadedModelFile = uilabel(app.ButtonGroup_2);
            app.LoadedModelFile.Tag = 'LoadedModelFile';
            app.LoadedModelFile.Text = '';
            app.Save = uibutton(app.ButtonGroup, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Tag = 'Save';
            app.Save.Text = 'Save/Apply';
            app.Cancel = uibutton(app.ButtonGroup, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Tag = 'Cancel';
            app.Cancel.Text = 'Cancel';
            app.LoadedFile = uilabel(app.ButtonGroup);
            app.LoadedFile.Tag = 'LoadedFile';
            app.LoadedFile.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppElectNoise(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end