classdef AppGraphicTESData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        XDataStr     matlab.ui.control.Label
        YDataStr     matlab.ui.control.Label
        YDataXData   matlab.ui.control.Button
        YDataTbath   matlab.ui.control.Button
        YDataRn      matlab.ui.control.Button
        XDataButton  matlab.ui.control.Button
        YDataButton  matlab.ui.control.Button
        Panel        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        OutputData = {}; % Description
        str = []; % Description
        
    end
    
    properties (Access = public)
        menu = []; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.figure1.Units = 'normalized';
            app.str = varargin{1};
            app.menu = varargin{2};
            
            position = get(app.figure1,'Position');
            set(app.figure1,'Color',[0 120 180]/255,'Position',...
                [0.5-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');
        end

        % Button pushed function: YDataButton
        function YDataButtonPushed(app, event)
            [s,~] = listdlg('PromptString','Select one model parameters:',...
                'SelectionMode','single','ListString',app.str);
            if isempty(s)
                return;
            end
            set(app.YDataStr,'Text',app.str{s});
            set([app.YDataRn app.YDataTbath],'Enable','on')
            if ~isempty(app.XDataStr.Text)
                set([app.YDataXData],'Enable','on')
            end
            figure(app.figure1);
            % close(app.figure1);
        end

        % Button pushed function: XDataButton
        function XDataButtonPushed(app, event)
            [s,~] = listdlg('PromptString','Select one model parameters:',...
                'SelectionMode','single','ListString',app.str);
            if isempty(s)
                return;
            end
            set(app.XDataStr,'Text',app.str{s});
            if ~isempty(app.YDataStr.Text)
                set([app.YDataXData],'Enable','on')
            end
            figure(app.figure1);
        end

        % Button pushed function: YDataRn
        function YDataRnPushed(app, event)
            Data.param1 = get(app.YDataStr,'Text');
            Data.case = 1;
            % app.menu.UserData = Data;
            % Buscamos la figura de la App A en el sistema por su Tag
            figuraAppA = findall(0, 'Type', 'uimenu', 'Tag', 'Menudummy');
            figuraAppA.UserData = Data;

            close(app.figure1);
        end

        % Button pushed function: YDataTbath
        function YDataTbathPushed(app, event)
            Data.param1 = get(app.YDataStr,'Text');
            Data.case = 2;
            % Buscamos la figura de la App A en el sistema por su Tag
            figuraAppA = findall(0, 'Type', 'uimenu', 'Tag', 'Menudummy');
            figuraAppA.UserData = Data;
            close(app.figure1);
        end

        % Button pushed function: YDataXData
        function YDataXDataPushed(app, event)
            Data.param1 = get(app.YDataStr,'Text');
            Data.param2 = get(app.XDataStr,'Text');
            Data.case = 3;
            % Buscamos la figura de la App A en el sistema por su Tag
            figuraAppA = findall(0, 'Type', 'uimenu', 'Tag', 'Menudummy');
            figuraAppA.UserData = Data;
            close(app.figure1);
        end

        % Callback function
        function figure1CloseRequest(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TES Data Plot';
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.YDataButton = uibutton(app.figure1, 'push');
            app.YDataButton.ButtonPushedFcn = createCallbackFcn(app, @YDataButtonPushed, true);
            app.YDataButton.Tag = 'YDataButton';
            app.YDataButton.Text = 'Select YData';
            app.XDataButton = uibutton(app.figure1, 'push');
            app.XDataButton.ButtonPushedFcn = createCallbackFcn(app, @XDataButtonPushed, true);
            app.XDataButton.Tag = 'XDataButton';
            app.XDataButton.Text = 'Select XData';
            app.YDataRn = uibutton(app.figure1, 'push');
            app.YDataRn.ButtonPushedFcn = createCallbackFcn(app, @YDataRnPushed, true);
            app.YDataRn.Tag = 'YDataRn';
            app.YDataRn.Enable = 'off';
            app.YDataRn.Text = 'YData vs. Rn';
            app.YDataTbath = uibutton(app.figure1, 'push');
            app.YDataTbath.ButtonPushedFcn = createCallbackFcn(app, @YDataTbathPushed, true);
            app.YDataTbath.Tag = 'YDataTbath';
            app.YDataTbath.Enable = 'off';
            app.YDataTbath.Text = 'YData vs. Tbath';
            app.YDataXData = uibutton(app.figure1, 'push');
            app.YDataXData.ButtonPushedFcn = createCallbackFcn(app, @YDataXDataPushed, true);
            app.YDataXData.Tag = 'YDataXData';
            app.YDataXData.Enable = 'off';
            app.YDataXData.Text = 'YData vs. XData';
            app.YDataStr = uilabel(app.figure1);
            app.YDataStr.Tag = 'YDataStr';
            app.YDataStr.Text = '';
            app.XDataStr = uilabel(app.figure1);
            app.XDataStr.Tag = 'XDataStr';
            app.XDataStr.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppGraphicTESData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end