classdef AppTF_Noise_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        Forward                         matlab.ui.control.Button
        Next                            matlab.ui.control.Button
        Previous                        matlab.ui.control.Button
        Rewind                          matlab.ui.control.Button
        Noise_Name                      matlab.ui.control.DropDown
        NoisefilesDropDownLabel         matlab.ui.control.Label
        TF_Name                         matlab.ui.control.DropDown
        TFfilesDropDownLabel            matlab.ui.control.Label
        TBath_popup                     matlab.ui.control.DropDown
        TbathcolecteddataDropDownLabel  matlab.ui.control.Label
        Noise_axes                      matlab.ui.control.UIAxes
        TF_axes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        varargin % Description
        VersionStr
        Files_Ind
        TBath_ind

        Nfiles % Description
    end
    
    methods (Access = public)
        
        function PlotTF_Noise(app,src)


            if isempty(src)
                src = app.figure1;
            end
            warning off;
            contents = cellstr(get(app.TBath_popup,'Items'));
            Str = contents{get(app.TBath_popup,'ValueIndex')};

            if ~isempty(strfind(Str,'Positive'))
                Tbaths{1} = floor([app.varargin{2}.PP.Tbath]*1e3);
                T_selected = str2double(Str(1:strfind(Str,'mK')-1));
                ind = find(Tbaths{1} == T_selected);
                StrCond = 'P';
            else
                Tbaths{2} = floor([app.varargin{2}.PN.Tbath]*1e3);
                T_selected = str2double(Str(1:strfind(Str,'mK')-1));
                ind = find(Tbaths{2} == T_selected);
                StrCond = 'N';
            end
            Tbath = str2double(Str(1:strfind(Str,'mK')-1));

            if ~isempty(strfind(Str,'Negative'))
                [~,Tind] = min(abs([app.varargin{2}.IVsetN.Tbath]*1e3-Tbath));
                IV = app.varargin{2}.IVsetN(Tind);
            else
                [~,Tind] = min(abs([app.varargin{2}.IVsetP.Tbath]*1e3-Tbath));
                IV = app.varargin{2}.IVsetP(Tind);
            end


            %% TF is drawn
            ind_Tbath = ind;
            eval(['files' StrCond ' = [app.varargin{2}.P' StrCond '(ind_Tbath).fileZ]'';';]);
            app.Nfiles = length(eval(['files' StrCond ';']));
            cla(app.TF_axes);
            hs = app.TF_axes;
            set(hs,'XScale','linear','YScale','linear')

            eval(['FileName = files' StrCond '{app.Files_Ind};']);
            FileName = FileName(find(FileName == filesep,1,'last')+1:end);

            if ~isempty(strfind(FileName,'PXI_TF'))
                Ib = sscanf(FileName,'PXI_TF_%fuA.txt')*1e-6;
            else
                Ib = sscanf(FileName,'TF_%fuA.txt')*1e-6;
            end
            Ib = Ib - app.varargin{2}.circuit.CurrOffset.Value;
            eval(['OP = app.varargin{2}.setTESOPfromIb(Ib,IV,app.varargin{2}.P' StrCond '(ind_Tbath).p,''' StrCond ''');']);

            data{1} = eval(['app.varargin{2}.P' StrCond '(ind_Tbath)']);
            data{2} = app.Files_Ind;
            data{3} = FileName;

            ztes = eval(['app.varargin{2}.P' StrCond '(ind_Tbath).ztes{app.Files_Ind};']);
            fZ = eval(['app.varargin{2}.P' StrCond '(ind_Tbath).fZ{app.Files_Ind};']);
            plot(hs,1e3*ztes,'.','Color',[0 0.447 0.741],...
                'MarkerFaceColor',[0 0.447 0.741],'MarkerSize',15,'ButtonDownFcn',{@DisplayAppResults},'UserData',data,'DisplayName','Experimental Data');
            hold(hs,'on');grid(hs,'on');

            set(hs,'LineWidth',2,'fontweight','normal','FontUnits','Normalized','FontSize',0.08);
            xlabel(hs,'Re(mZ)','fontweight','normal','FontUnits','Normalized','FontSize',0.08);
            ylabel(hs,'Im(mZ)','fontweight','normal','FontUnits','Normalized','FontSize',0.08);%title('Ztes with fits (red)');
            ImZmin = min(imag(1e3*ztes));


            plot(hs,1e3*fZ(:,1),1e3*fZ(:,2),'r','LineWidth',2,'ButtonDownFcn',{@DisplayAppResults},'UserData',data,'DisplayName',...
                eval(['app.varargin{2}.P' StrCond '(ind_Tbath).ElecThermModel{app.Files_Ind}']));


            axis(hs,'tight');
            r0 = data{1}.p(app.Files_Ind).rp;
            Z0 = data{1}.p(app.Files_Ind).Z0;
            Zinf = data{1}.p(app.Files_Ind).Zinf;
            title(hs,strcat(num2str(nearest(r0*100),'%3.0f'),'%Rn'),'FontUnits','Normalized','FontSize',0.08);
            
            if abs(Z0-Zinf) < app.varargin{2}.ElectrThermalModel.Z0_Zinf_Thrs
                set(get(findobj(hs,'type','axes'),'title'),'Color','r');
            else
                set(get(findobj(hs,'type','axes'),'title'),'Color','k');
            end
            hold(hs,'off');

            if src == app.figure1||src == app.TBath_popup
                clear FilesStr;
                for k = 1:length(eval(['files' StrCond]))
                    eval(['Files = files' StrCond '{k};']);
                    FilesStr{k,1} = Files(find(Files == filesep,1,'last')+1:end);
                end
                set(app.TF_Name,'Items',FilesStr,'ValueIndex',1);
            end
            set(app.TF_axes,'ButtonDownFcn',{@DisplayAppResults},'UserData',data);
            axis(app.TF_axes,'tight');

            [app.varargin{2}.ElectrThermalModel.bool_components app.varargin{2}.ElectrThermalModel.Selected_tipo];
            %% Noise is drawn
            cla(app.Noise_axes);
            hs1 = app.Noise_axes;
            eval(['filesNoise' StrCond ' = [app.varargin{2}.P' StrCond '(ind_Tbath).fileNoise]'';';]);
            eval(['fNoise{app.Files_Ind} = app.varargin{2}.P' StrCond '(ind_Tbath).fNoise{app.Files_Ind};';]);
            eval(['SigNoise{app.Files_Ind} = app.varargin{2}.P' StrCond '(ind_Tbath).SigNoise{app.Files_Ind};';]);


            eval(['FileName = filesNoise' StrCond '{app.Files_Ind};']);
            FileName = FileName(find(FileName == filesep,1,'last')+1:end);

            Ib = sscanf(FileName,strcat(app.varargin{2}.NoiseOpt.NoiseBaseName(2:end-1),'_%fuA.txt'))*1e-6; %%%HP_noise para ZTES18.!!!
            Ib = Ib - app.varargin{2}.circuit.CurrOffset.Value;
            eval(['OP = app.varargin{2}.setTESOPfromIb(Ib,IV,app.varargin{2}.P' StrCond '(ind_Tbath).p,''' StrCond ''');']);
            if app.varargin{2}.ElectrThermalModel.bool_Mjo == 1
                %     M = OP.M;
                M = data{1}.p(app.Files_Ind).M;
            else
                M = 0;
            end
            f = logspace(1,5,1001)';
            % auxnoise = obj.noisesim(OP,M,f);
            if length(fNoise{app.Files_Ind}) ~= length(f)
                SigNoise{app.Files_Ind} = spline(fNoise{app.Files_Ind},SigNoise{app.Files_Ind},f); % Todos los ruidos a 321 puntos
                fNoise{app.Files_Ind} = f;
            end

            auxnoise = app.varargin{2}.ElectrThermalModel.noisesim(app.varargin{2},OP,0,f,StrCond);

            app.varargin{2}.ElectrThermalModel.Plot(fNoise{app.Files_Ind},SigNoise{app.Files_Ind},auxnoise,OP,hs1);

            hold(hs1,'off');
            if src == app.figure1||src == app.TBath_popup
                clear FilesStr;
                for k = 1:length(eval(['filesNoise' StrCond]))
                    eval(['Files = filesNoise' StrCond '{k};']);
                    FilesStr{k,1} = Files(find(Files == filesep,1,'last')+1:end);
                end
                set(app.Noise_Name,'Items',FilesStr,'ValueIndex',1);
            end
            set(app.Noise_axes,'ButtonDownFcn',{@HandleAppBoolComp},'UserData',app.varargin{2});
            % set(app.Noise_axes,'ButtonDownFcn',{@HandleBoolComp},'UserData',app.varargin{2}, [ax_frame(3) diff(app.varargin{2}.ElectrThermalModel.Noise_HighFreq) ax_frame(4)],'FaceColor',[214 232 217 127.5]/255);

            guidata(src, app)
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.figure1.Units = 'normalized';
            app.varargin = varargin;
            position = get(app.figure1,'Position');
            set(app.figure1,'Color',[0 120 180]/255,'Position',...
                [0.5-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');

            app.VersionStr = app.varargin{2}.version; %'ZarTES v4.1';
            set(app.figure1,'Name','TF_Viewer');
            % Updating the popup menu


            Extnd = {[]};
            try
                Tbaths{1} = [app.varargin{2}.PP.Tbath]*1e3;
                Extnd(1:length(Tbaths{1}),1) = {'mK Positive Ibias'};
            catch
                Tbaths{1} = [];
            end
            try
                Tbaths{2} = [app.varargin{2}.PN.Tbath]*1e3;
                Extnd(length(Tbaths{1})+1:length(Tbaths{2})+length(Tbaths{1}),1) = {'mK Negative Ibias'};
            catch
                Tbaths{2} = [];
            end
            if ~isempty(Tbaths{1})||~isempty(Tbaths{2})
                TbathPopStr = [num2cell(Tbaths{1}') Extnd(1:length(Tbaths{1}),1); num2cell(Tbaths{2}') Extnd(length(Tbaths{1})+1:length(Tbaths{2})+length(Tbaths{1}),1)];
                Str = {[]};
                for j = 1:size(TbathPopStr,1)
                    Str{j,1} = [num2str(TbathPopStr{j,1}) TbathPopStr{j,2}];
                end
                app.TBath_ind = 1;
                set(app.TBath_popup,'Items',Str,'ValueIndex',app.TBath_ind);
            else
                warndlg('First use FitZset method!',app.VersionStr);
                delete(app.figure1);
                return;
            end

            app.Files_Ind = 1;
            try
                PlotTF_Noise(app,[]);
                Ok = 1;
            catch
                Ok = 0;
            end
            if ~Ok
                warndlg('First use FitZset method!',app.VersionStr);
                delete(app.figure1);
                return;
            end
            % app = guidata(hObject);
            set([app.Previous app.Rewind],'Enable','off')


            % Update handles structure
            guidata(app.figure1, app);
            % guidata(hObject, handles);

        end

        % Value changed function: TBath_popup
        function TBath_popupValueChanged(app, event)
            % value = app.TBath_popup.Value;
            app.Files_Ind = 1;
            if app.Files_Ind == 1
                set([app.Forward app.Next],'Enable','on');
                set([app.Rewind app.Previous],'Enable','off');
            elseif app.Files_Ind == app.Nfiles
                set([app.Forward app.Next],'Enable','off');
                set([app.Rewind app.Previous],'Enable','on');
            else
                set([app.Rewind app.Previous app.Forward app.Next],'Enable','on');
            end
            PlotTF_Noise(app,event.Source);
            
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
            app.Files_Ind = max(app.Files_Ind - 1,1);
            if event.Source ~= app.Rewind
                if app.Files_Ind == 1
                    set([app.Previous app.Rewind],'Enable','off')
                else
                    set([app.Previous app.Rewind],'Enable','on')
                end
            end
            set([app.TF_Name app.Noise_Name],'ValueIndex',app.Files_Ind);
            PlotTF_Noise(app,event.Source);
            if event.Source ~= app.Rewind
                set([app.Next app.Forward],'Enable','on')
            end
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            app.Files_Ind = min(app.Files_Ind + 1,app.Nfiles);
            if event.Source ~= app.Forward
                if app.Files_Ind == app.Nfiles
                    set([app.Next app.Forward],'Enable','off')
                else
                    set([app.Next app.Forward],'Enable','on')
                end
            end
            set([app.TF_Name app.Noise_Name],'ValueIndex',app.Files_Ind);
            PlotTF_Noise(app,event.Source);
            if event.Source ~= app.Forward
                set([app.Previous app.Rewind],'Enable','on')
            end
        end

        % Button pushed function: Rewind
        function RewindButtonPushed(app, event)
            if app.Files_Ind > 1
                hds = findobj(event.Source.Parent,'Type','Uicontrol');
                set(hds,'Enable','off');
            end
            while app.Files_Ind > 1
                Flag = get(app.Rewind,'UserData');
                if ~isempty(Flag)
                    break;
                end
                app.PreviousButtonPushed(event);
                
                pause(0.2);
            end
            if app.Files_Ind == 1
                set(hds,'Enable','on');
                set([app.Previous app.Rewind],'Enable','off')
            else
                set(hds,'Enable','on');
            end
            set([app.Rewind app.Forward],'UserData',[]);
        end

        % Button pushed function: Forward
        function ForwardButtonPushed(app, event)
            if app.Files_Ind < app.Nfiles
                hds = findobj(event.Source.Parent,'Type','Uicontrol');
                set(hds,'Enable','off');
            end
            while app.Files_Ind < app.Nfiles
                Flag = get(app.Forward,'UserData');
                if ~isempty(Flag)
                    break;
                end
                app.NextButtonPushed(event)
                
                pause(0.2);
            end
            if app.Files_Ind == app.Nfiles
                set(hds,'Enable','on');
                set([app.Next app.Forward],'Enable','off')
            else
                set(hds,'Enable','on');
            end
            set([app.Rewind app.Forward],'UserData',[]);
        end

        % Value changed function: TF_Name
        function TF_NameValueChanged(app, event)
            
            app.Files_Ind = app.TF_Name.ValueIndex;
            set(app.Noise_Name,'ValueIndex',app.Files_Ind);
            if app.Files_Ind == 1
                set([app.Forward app.Next],'Enable','on');
                set([app.Rewind app.Previous],'Enable','off');
            elseif app.Files_Ind == app.Nfiles
                set([app.Forward app.Next],'Enable','off');
                set([app.Rewind app.Previous],'Enable','on');
            else
                set([app.Rewind app.Previous app.Forward app.Next],'Enable','on');
            end
            PlotTF_Noise(app,event.Source);

        end

        % Value changed function: Noise_Name
        function Noise_NameValueChanged(app, event)
            
            app.Files_Ind = app.Noise_Name.ValueIndex;
            set(app.TF_Name,'ValueIndex',app.Files_Ind);
            if app.Files_Ind == 1
                set([app.Forward app.Next],'Enable','on');
                set([app.Rewind app.Previous],'Enable','off');
            elseif app.Files_Ind == app.Nfiles
                set([app.Forward app.Next],'Enable','off');
                set([app.Rewind app.Previous],'Enable','on');
            else
                set([app.Rewind app.Previous app.Forward app.Next],'Enable','on');
            end
            PlotTF_Noise(app,event.Source);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TF_Viewer';
            app.figure1.Tag = 'TF_axes';
            app.TF_axes = uiaxes(app.figure1);
            app.Noise_axes = uiaxes(app.figure1);
            app.Noise_axes.Tag = 'Noise_axes';
            app.TbathcolecteddataDropDownLabel = uilabel(app.figure1);
            app.TbathcolecteddataDropDownLabel.Text = 'Tbath colected data';
            app.TBath_popup = uidropdown(app.figure1);
            app.TBath_popup.Items = {''};
            app.TBath_popup.ValueChangedFcn = createCallbackFcn(app, @TBath_popupValueChanged, true);
            app.TBath_popup.Tag = 'TBath_popup';
            app.TBath_popup.Value = '';
            app.TFfilesDropDownLabel = uilabel(app.figure1);
            app.TFfilesDropDownLabel.Text = 'TF files';
            app.TF_Name = uidropdown(app.figure1);
            app.TF_Name.Items = {};
            app.TF_Name.ValueChangedFcn = createCallbackFcn(app, @TF_NameValueChanged, true);
            app.TF_Name.Tag = 'TF_Name';
            app.TF_Name.Value = {};
            app.NoisefilesDropDownLabel = uilabel(app.figure1);
            app.NoisefilesDropDownLabel.Text = 'Noise files';
            app.Noise_Name = uidropdown(app.figure1);
            app.Noise_Name.Items = {''};
            app.Noise_Name.ValueChangedFcn = createCallbackFcn(app, @Noise_NameValueChanged, true);
            app.Noise_Name.Tag = 'Noise_Name';
            app.Noise_Name.Value = '';
            app.Rewind = uibutton(app.figure1, 'push');
            app.Rewind.ButtonPushedFcn = createCallbackFcn(app, @RewindButtonPushed, true);
            app.Rewind.Tag = 'Rewind';
            app.Rewind.Text = '<<';
            app.Previous = uibutton(app.figure1, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Tag = 'Previous';
            app.Previous.Text = 'Previous';
            app.Next = uibutton(app.figure1, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Tag = 'Next';
            app.Next.Text = 'Next';
            app.Forward = uibutton(app.figure1, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.Forward.Tag = 'Forward';
            app.Forward.Text = '>>';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTF_Noise_Viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end