classdef ControlTemp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BlueforsTempControlUIFigure    matlab.ui.Figure
        MixingChamberTempControlPanel  matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        StartControlButton             matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        PowermWEditField               matlab.ui.control.NumericEditField
        PowermWEditFieldLabel          matlab.ui.control.Label
        DEditField                     matlab.ui.control.NumericEditField
        IEditField                     matlab.ui.control.NumericEditField
        PEditField                     matlab.ui.control.NumericEditField
        DEditFieldLabel                matlab.ui.control.Label
        IEditFieldLabel                matlab.ui.control.Label
        PEditFieldLabel                matlab.ui.control.Label
        controlby                      matlab.ui.control.DropDown
        AlgorithmTempControlLabel      matlab.ui.control.Label
        MaxPowermWEditField            matlab.ui.control.NumericEditField
        MaxPowermWEditFieldLabel       matlab.ui.control.Label
        HeaterOptionsLabel             matlab.ui.control.Label
        TempMCKEditField               matlab.ui.control.NumericEditField
        TempMCKEditFieldLabel          matlab.ui.control.Label
        TempSetKEditField              matlab.ui.control.NumericEditField
        TempSetKEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Green color - Active
        Active_Color = [120 170 50]/255;
        % Gray color - Disable elements
        Disable_Color = [204 204 204]/255;Property % Description        
        PIDError = zeros(2,1);
        U = [0 0];
        E = [0 0 0];
        SetPt = 0;
        PwrInit = 0;        
        Period = 7;
        timerTemp;
        timerPower;
        timerLamp;
        BF;

    end    
    
    methods (Access = public)
        
        function app = update_Temp_display(app)
            % T_MC = app.BF.ReadTemp;
            T_MC = 70+rand;
            app.TempMCKEditField.Value = T_MC;
        end

        function app = auto_power(app)
            
            if app.StartControlButton.BackgroundColor == app.Active_Color
                % app.SetPt = app.BF.ReadSetPoint;
                app.TempSetKEditField.Value = app.SetPt;
                

                T_MC = app.TempMCKEditField.Value;
                while isnan(app.TempMCKEditField.Value)
                    T_MC = app.TempMCKEditField.Value;
                end

                dt = 7;
                app.E(1) = app.SetPt-T_MC;
                app.U(1) = max(0,app.U(2) + ...
                    app.BF.P*(app.E(1)-app.E(2)) + ...
                    (app.BF.P/app.BF.I)*app.E(1)*dt);

                app.E(3) = app.E(2);
                app.E(2) = app.E(1);
                %     disp(handles.U);
                app.U(2) = app.U(1);
                SetPower = app.U(1);
                % app.BF.SetPower(SetPower);
                disp(app.E(1));


            end            
        end

        function app = LampChange(app)            
            app.Lamp.Color = [0 1 0];
            pause(app.timerLamp.Period/2)
            app.Lamp.Color = [0.6 0.6 0.6];
            pause(app.timerLamp.Period/2)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BlueforsTempControlUIFigure.Units = 'normalized';
            position = get(app.BlueforsTempControlUIFigure,'Position');
            set(app.BlueforsTempControlUIFigure,'Color',[0 120 180]/255,'Position',...
                [0.25-position(3)/2 0.5-position(4)/2 position(3) position(4)],...
                'Units','Normalized');

            app.controlby_Callback;


            app.timerTemp = timer(...
            'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
            'Period', app.Period, ...                        % Initial period is 1 sec.
            'UserData',app,'Name','TEMPtimer');
            app.timerTemp.TimerFcn = @(src, event) update_Temp_display(app);
            start(app.timerTemp);

            app.timerPower = timer(...
            'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
            'Period', app.Period, ...                        % Initial period is 1 sec.
            'UserData',app,'Name','POWtimer');
            app.timerPower.TimerFcn = @(src, event) auto_power(app);

            app.timerLamp = timer(...
            'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
            'Period', 2, ...                        % Initial period is 1 sec.
            'UserData',app,'Name','Lamptimer');
            app.timerLamp.TimerFcn = @(src, event) LampChange(app);


            rutaApp = fileparts(mfilename('fullpath'));
            addpath(rutaApp);

            app.BF = BlueFors;
            % app.BF = app.BF.Constructor;
            app.BF.P = app.PEditField.Value;
            app.BF.I = app.IEditField.Value;
            app.BF.D = app.DEditField.Value;
            % app.BF.SetMaxPower(1e-3);
            % app.MaxPowermWEditField.Value = app.BF.ReadMaxPower*1e3;
            % T_MC = app.BF.ReadTemp;
            T_MC = 70+rand;
            app.TempMCKEditField.Value = T_MC;

            app.SetPt = 0.05;
            % app.SetPt = app.BF.ReadSetPoint;

            
            app.PowermWEditField.Value = app.PwrInit;

            
        end

        % Close request function: BlueforsTempControlUIFigure
        function BlueforsTempControlUIFigureCloseRequest(app, event)
            stop(app.timerTemp);

            delete(app)
            
        end

        % Value changed function: TempSetKEditField
        function SetPoint_Callback(app, event)
            app.SetPt = app.TempSetKEditField.Value;
            % app.BF.SetTemp(app.SetPt);
        end

        % Button pushed function: StartControlButton
        function Go2Temp_Callback(app, event)
            set(app.StartControlButton,'BackgroundColor',app.Active_Color);


            % if app.BF.ReadPIDStatus
            %     app.BF.SetTempControl(0); %Manual
            %     app.BF.SetPt = app.SetPt;
            % end

            switch app.timerPower.Running 
                case 'off'
                    start(app.timerPower);
                otherwise
            end
            switch app.timerLamp.Running 
                case 'off'
                    start(app.timerLamp);
                otherwise
            end


        end

        % Button pushed function: StopButton
        function Stop_Callback(app, event)
            set(app.StartControlButton,'BackgroundColor',app.Disable_Color);
            stop(app.timerLamp)            
        end

        % Value changed function: controlby
        function controlby_Callback(app, event)
            value = app.controlby.Value;
            switch value
                case 'PID'  % PID
                    app.PowermWEditField.Enable = 'off';
                    % Enable self containers
                    set([app.PEditField app.IEditField app.DEditField app.StartControlButton app.StopButton],'Enable','on');

                case 'Manual'  % Manual
                    app.PowermWEditField.Enable = 'on';
                    % Enable self containers
                    set([app.PEditField app.IEditField app.DEditField app.StartControlButton app.StopButton],'Enable','off');
                    stop(app.timerPower);

                    % app.PowermWEditField.Value = app.BF.ReadPower*1e3;
        
                case 3  % Others (could be IA-based)
                    set([app.PEditField app.IEditField app.DEditField app.StartControlButton app.StopButton],'Enable','off');
                    stop(app.timerPower);
                otherwise
            end
            
        end

        % Value changed function: PEditField
        function P_Callback(app, event)
            app.BF.P = app.PEditField.Value;
            app.BF.I = app.IEditField.Value;
            app.BF.D = app.DEditField.Value;            
        end

        % Value changed function: IEditField
        function I_Callback(app, event)
            app.BF.P = app.PEditField.Value;
            app.BF.I = app.IEditField.Value;
            app.BF.D = app.DEditField.Value;
        end

        % Value changed function: DEditField
        function D_Callback(app, event)
            app.BF.P = app.PEditField.Value;
            app.BF.I = app.IEditField.Value;
            app.BF.D = app.DEditField.Value;
        end

        % Value changed function: MaxPowermWEditField
        function MaxPower_Callback(app, event)
            value = app.MaxPowermWEditField.Value;
            if ~isempty(value)&&~isnan(value)
                if value <= 0
                    value = 0;
                    app.MaxPowermWEditField.Value = value;
                elseif value >= 5 % Limite de potencia máxima interna
                    button = questdlg('Max Power beyond 5 mW. Are you sure to continue?','Warning','Yes','No','No');
                    switch(button)
                        case 'Yes'
                        case 'No'
                            app.MaxPowermWEditField.Value = app.BF.ReadMaxPower*1e3;
                            return;
                    end
                end
            else
                value = 0;
                app.MaxPowermWEditField.Value = value;
            end
            % app.BF.SetMaxPower(value*1e-3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BlueforsTempControlUIFigure = uifigure('Visible', 'off');
            app.BlueforsTempControlUIFigure.Name = 'Bluefors Temp Control';
            app.BlueforsTempControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @BlueforsTempControlUIFigureCloseRequest, true);
            app.MixingChamberTempControlPanel = uipanel(app.BlueforsTempControlUIFigure);
            app.MixingChamberTempControlPanel.Title = 'Mixing Chamber Temp Control';
            app.TempSetKEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.TempSetKEditFieldLabel.Text = 'Temp Set (K)';
            app.TempSetKEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.TempSetKEditField.Limits = [0 0.3];
            app.TempSetKEditField.ValueChangedFcn = createCallbackFcn(app, @SetPoint_Callback, true);
            app.TempMCKEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.TempMCKEditFieldLabel.Text = 'Temp (M/C) (K)';
            app.TempMCKEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.HeaterOptionsLabel = uilabel(app.MixingChamberTempControlPanel);
            app.HeaterOptionsLabel.Text = 'Heater Options';
            app.MaxPowermWEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.MaxPowermWEditFieldLabel.Text = 'Max Power (mW)';
            app.MaxPowermWEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.MaxPowermWEditField.LowerLimitInclusive = 'off';
            app.MaxPowermWEditField.UpperLimitInclusive = 'off';
            app.MaxPowermWEditField.Limits = [0 Inf];
            app.MaxPowermWEditField.ValueChangedFcn = createCallbackFcn(app, @MaxPower_Callback, true);
            app.MaxPowermWEditField.Value = 1;
            app.AlgorithmTempControlLabel = uilabel(app.MixingChamberTempControlPanel);
            app.AlgorithmTempControlLabel.Text = 'Algorithm Temp Control';
            app.controlby = uidropdown(app.MixingChamberTempControlPanel);
            app.controlby.Items = {'PID', 'Manual'};
            app.controlby.ValueChangedFcn = createCallbackFcn(app, @controlby_Callback, true);
            app.controlby.Value = 'PID';
            app.PEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.PEditFieldLabel.Text = 'P';
            app.IEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.IEditFieldLabel.Text = 'I';
            app.DEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.DEditFieldLabel.Text = 'D';
            app.PEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.PEditField.Limits = [0 Inf];
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @P_Callback, true);
            app.PEditField.Tag = 'P';
            app.PEditField.Value = 0.001;
            app.IEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.IEditField.Limits = [0 Inf];
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @I_Callback, true);
            app.IEditField.Value = 2;
            app.DEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.DEditField.Limits = [0 Inf];
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @D_Callback, true);
            app.PowermWEditFieldLabel = uilabel(app.MixingChamberTempControlPanel);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.MixingChamberTempControlPanel, 'numeric');
            app.Lamp = uilamp(app.MixingChamberTempControlPanel);
            app.Lamp.Tag = 'lamp';
            app.StartControlButton = uibutton(app.MixingChamberTempControlPanel, 'push');
            app.StartControlButton.ButtonPushedFcn = createCallbackFcn(app, @Go2Temp_Callback, true);
            app.StartControlButton.Text = 'Start Control';
            app.StopButton = uibutton(app.MixingChamberTempControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop_Callback, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.BlueforsTempControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlTemp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BlueforsTempControlUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BlueforsTempControlUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BlueforsTempControlUIFigure)
        end
    end
end